classdef FilterData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StreamingFilterWindowUIFigure  matlab.ui.Figure
        Hyperlink                      matlab.ui.control.Hyperlink
        Label                          matlab.ui.control.Label
        ReadyLamp_Label                matlab.ui.control.Label
        ReadyLamp                      matlab.ui.control.Lamp
        Filtering_Panel                matlab.ui.container.Panel
        FilterOrderSpinner             matlab.ui.control.Spinner
        FilterOrder4defaultLabel       matlab.ui.control.Label
        DeleteFiltersButton            matlab.ui.control.Button
        VisualizeFiltersButton         matlab.ui.control.Button
        ApplyVisualizeButton           matlab.ui.control.Button
        Filter_TypeofLFPsDropDown      matlab.ui.control.DropDown
        SamplingAcquisitionLabel       matlab.ui.control.Label
        Filter_TypeofFilterDropDown    matlab.ui.control.DropDown
        TypeofFilterDropDownLabel      matlab.ui.control.Label
        Filter_UpperBoundHz_EditField  matlab.ui.control.NumericEditField
        UpperBoundHzEditFieldLabel     matlab.ui.control.Label
        Filter_LowerBoundHz_EditField  matlab.ui.control.NumericEditField
        LowerBoundHzEditFieldLabel     matlab.ui.control.Label
        Closeall_Button                matlab.ui.control.Button
        FilterStreamingDataLabel       matlab.ui.control.Label
    end

    properties (Access = private)
        obj % NCNPERCEPT objects
        filter_indx
        current_patient
        data_type

    end
    
    methods (Access = private)

        function status = selectSignalToFilter(app)
            status = 1;
            % Check if data is filtered, select data
            if isempty(app.obj.ncnpercept_patient{app.current_patient}{1}.streaming_obj.streaming_parameters.filtered_data.data)
                disp('Data is not filtered');
                answer = questdlg('Which data do you want to filter?', ...
                    '', ...
                    'Raw','ECG Clean','ECG Clean');
                if answer ~= ""
                    app.data_type = answer;
                else
                    status = 0;
                end
            else
                disp('Data is filtered');
                answer = questdlg('Which data do you want to filter?', ...
                    '', ...
                    'Raw', 'ECG Clean', 'Latest Filtered', 'Latest Filtered');
                if answer ~= ""
                    app.data_type = answer;
                else
                    status = 0;
                end
            end

        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, obj_arg, patient_arg)
            app.obj = obj_arg; % NCNPERCEPT objects argument from Main GUI
            app.current_patient = 1;
            app.filter_indx= 1;
            
            for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                num_filter = numel(app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.filter_type);
                if num_filter ~= 0
                    app.DeleteFiltersButton.Enable = true;
                    app.VisualizeFiltersButton.Enable = true;
                    break
                end
            end
        end

        % Button pushed function: Closeall_Button
        function Closeall_ButtonPushed(app, event)
            all_fig = findall(0, 'type', 'figure');            
                        delete(all_fig(~contains({all_fig.Name}, {'DBScope', 'Scan Artifacts Window', 'Streaming Filter Window'}))); 
        end

        % Value changed function: Filter_TypeofFilterDropDown
        function Filter_TypeofFilterDropDownValueChanged(app, event)
            app.Filter_LowerBoundHz_EditField.Enable = false;
            app.Filter_UpperBoundHz_EditField.Enable = false;

            % Select filter to apply
            switch app.Filter_TypeofFilterDropDown.Value
                case 'Low pass'
                    app.Filter_UpperBoundHz_EditField.Enable = true;
                case 'High pass'
                    app.Filter_LowerBoundHz_EditField.Enable = true;
                case 'Bandpass'
                    app.Filter_LowerBoundHz_EditField.Enable = true;
                    app.Filter_UpperBoundHz_EditField.Enable = true;
                case 'Stop band'
                    app.Filter_LowerBoundHz_EditField.Enable = true;
                    app.Filter_UpperBoundHz_EditField.Enable = true;
                otherwise
            end
        end

        % Value changed function: Filter_TypeofLFPsDropDown
        function Filter_TypeofLFPsDropDownValueChanged(app, event)
            value = app.Filter_TypeofLFPsDropDown.Value;
            app.ApplyVisualizeButton.Enable = true;
        end

        % Value changed function: Filter_LowerBoundHz_EditField
        function Filter_LowerBoundHz_EditFieldValueChanged(app, event)
            value = app.Filter_LowerBoundHz_EditField.Value;
        end

        % Value changed function: Filter_UpperBoundHz_EditField
        function Filter_UpperBoundHz_EditFieldValueChanged(app, event)
            value = app.Filter_UpperBoundHz_EditField.Value;
        end

        % Button pushed function: ApplyVisualizeButton
        function ApplyVisualizeButtonPushed(app, event)
            app.ReadyLamp_Label.Text = 'Busy';
            app.ReadyLamp.Color = [0.64,0.08,0.18];
            drawnow;

            % Get filter lower and upper bound values
            lowb = app.Filter_LowerBoundHz_EditField.Value;
            upb  = app.Filter_UpperBoundHz_EditField.Value;

            order = app.FilterOrderSpinner.Value;

            % Apply selected filter
            switch app.Filter_TypeofLFPsDropDown.Value
                case '250Hz (Online Streaming)'
                    status = app.selectSignalToFilter();
                    if status
                        switch app.Filter_TypeofFilterDropDown.Value
                            case 'Low pass'
                                for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                                    app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.filtStreaming( 250, app.data_type, app.Filter_TypeofFilterDropDown.Value, order, upb )
                                end
                            case 'High pass'
                                for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                                    app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.filtStreaming( 250, app.data_type, app.Filter_TypeofFilterDropDown.Value, order, lowb )
                                end
                            case 'Bandpass'
                                for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                                    app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.filtStreaming( 250, app.data_type, app.Filter_TypeofFilterDropDown.Value, order, [lowb, upb] )
                                end
                            case 'Stop band'
                                for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                                    app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.filtStreaming( 250, app.data_type, app.Filter_TypeofFilterDropDown.Value, order, [lowb, upb] )
                                end
                            otherwise
                        end
                        VisualizationWindow( app.obj, app.current_patient, "One Filter", 0 );

                        app.DeleteFiltersButton.Enable = true;
                        app.VisualizeFiltersButton.Enable = true;
                    end
                otherwise
                    
            end

            app.ReadyLamp_Label.Text = 'Ready';
            app.ReadyLamp.Color = [0.47,0.67,0.19];
            drawnow;
        end

        % Button pushed function: DeleteFiltersButton
        function DeleteFiltersButtonPushed(app, event)
            app.ReadyLamp_Label.Text = 'Busy';
            app.ReadyLamp.Color = [0.64,0.08,0.18];
            drawnow;
            
            % Choose filtered signal to delete
            for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                num_filter = numel(app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.filter_type);
                if num_filter ~= 0
                    break
                end
            end
            
            filt_list = cell(num_filter, 1);
            for i = 1:num_filter
                filter_type = app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.filter_type{i};
                filt_list{i} = num2str(i) + " - " + filter_type + "; ";
                if filter_type == "Low pass"
                    filt_list{i} = filt_list{i} + ...
                    app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.bounds{i}(2);
                elseif filter_type == "High pass"
                    filt_list{i} = filt_list{i} + ...
                    app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.bounds{i}(1);
                elseif filter_type == "Bandpass"
                    filt_list{i} = filt_list{i} + ...
                    app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.bounds{i}(1) + ...
                    "-" + ...
                    app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.bounds{i}(2);
                elseif filter_type == "Stop band"
                    filt_list{i} = filt_list{i} + ...
                    app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.bounds{i}(1) + ...
                    "-" + ...
                    app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.bounds{i}(2);
                end
                filt_list{i} = filt_list{i} + "Hz; Applied to: " + ...
                    app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.original_data_ID{i};
            end
        
            [app.filter_indx, ~] = listdlg('PromptString','Select filtered signal.',...
            'SelectionMode','multiple','ListString', filt_list);
            
            if numel(app.filter_indx) ~= 0 % Not cancelled
                % Delete previously applied filters
                for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                    if numel(app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.filter_type) ~= 0
                        app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.deleteFilters( app.filter_indx );
                    end
                end
                
            end

            app.ReadyLamp_Label.Text = 'Ready';
            app.ReadyLamp.Color = [0.47,0.67,0.19];
            drawnow;
                       
            if num_filter - numel(app.filter_indx) == 0
                app.DeleteFiltersButton.Enable = false;
                app.VisualizeFiltersButton.Enable = false;
            end
        end

        % Button pushed function: VisualizeFiltersButton
        function VisualizeFiltersButtonPushed(app, event)
            app.ReadyLamp_Label.Text = 'Busy';
            app.ReadyLamp.Color = [0.64,0.08,0.18];
            drawnow;
            
            % Choose filtered signals to display
            for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                num_filter = numel(app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.filter_type);
                if num_filter ~= 0
                    break
                end
            end

            filt_list = cell(num_filter, 1);
            for i = 1:num_filter
                filter_type = app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.filter_type{i};
                filt_list{i} = num2str(i) + " - " + filter_type + "; ";
                if filter_type == "Low pass"
                    filt_list{i} = filt_list{i} + ...
                    app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.bounds{i}(2);
                elseif filter_type == "High pass"
                    filt_list{i} = filt_list{i} + ...
                    app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.bounds{i}(1);
                elseif filter_type == "Bandpass"
                    filt_list{i} = filt_list{i} + ...
                    app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.bounds{i}(1) + ...
                    "-" + ...
                    app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.bounds{i}(2);
                elseif filter_type == "Stop band"
                    filt_list{i} = filt_list{i} + ...
                    app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.bounds{i}(1) + ...
                    "-" + ...
                    app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.bounds{i}(2);
                end
                filt_list{i} = filt_list{i} + "Hz; Applied to: " + ...
                    app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.original_data_ID{i};
            end
        
            [app.filter_indx, ~] = listdlg('PromptString','Select filtered signal.',...
            'SelectionMode','multiple','ListString', filt_list, 'ListSize', [250 300]);
            
            if numel(app.filter_indx) == 1 % Not cancelled
                for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                    if numel(app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters.filtered_data.filter_type) ~= 0
                        VisualizationWindow( app.obj, app.current_patient, "One Filter indx", app.filter_indx );
                    end
                end                
            elseif numel(app.filter_indx) == 2 % Not cancelled
               msgbox(['The selected filteres were the index:' num2str(app.filter_indx)])
                VisualizationWindow( app.obj, app.current_patient, "Multi Filter", app.filter_indx );
            elseif numel(app.filter_indx) == 0
                
            else
                warndlg('Please, select maximum of 2 filtered signals.')
            end

            app.ReadyLamp_Label.Text = 'Ready';
            app.ReadyLamp.Color = [0.47,0.67,0.19];
            drawnow;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StreamingFilterWindowUIFigure = uifigure('Visible', 'off');
            app.StreamingFilterWindowUIFigure.Name = 'Streaming Filter Window';
            app.FilterStreamingDataLabel = uilabel(app.StreamingFilterWindowUIFigure);
            app.FilterStreamingDataLabel.Text = 'Filter Streaming Data';
            app.Closeall_Button = uibutton(app.StreamingFilterWindowUIFigure, 'push');
            app.Closeall_Button.ButtonPushedFcn = createCallbackFcn(app, @Closeall_ButtonPushed, true);
            app.Closeall_Button.Text = 'Close all plots';
            app.Filtering_Panel = uipanel(app.StreamingFilterWindowUIFigure);
            app.LowerBoundHzEditFieldLabel = uilabel(app.Filtering_Panel);
            app.LowerBoundHzEditFieldLabel.Text = 'Lower Bound [Hz]';
            app.Filter_LowerBoundHz_EditField = uieditfield(app.Filtering_Panel, 'numeric');
            app.Filter_LowerBoundHz_EditField.Limits = [0 125];
            app.Filter_LowerBoundHz_EditField.ValueChangedFcn = createCallbackFcn(app, @Filter_LowerBoundHz_EditFieldValueChanged, true);
            app.Filter_LowerBoundHz_EditField.Enable = 'off';
            app.UpperBoundHzEditFieldLabel = uilabel(app.Filtering_Panel);
            app.UpperBoundHzEditFieldLabel.Text = 'Upper Bound [Hz]';
            app.Filter_UpperBoundHz_EditField = uieditfield(app.Filtering_Panel, 'numeric');
            app.Filter_UpperBoundHz_EditField.Limits = [0 125];
            app.Filter_UpperBoundHz_EditField.ValueChangedFcn = createCallbackFcn(app, @Filter_UpperBoundHz_EditFieldValueChanged, true);
            app.Filter_UpperBoundHz_EditField.Enable = 'off';
            app.TypeofFilterDropDownLabel = uilabel(app.Filtering_Panel);
            app.TypeofFilterDropDownLabel.Text = 'Type of Filter';
            app.Filter_TypeofFilterDropDown = uidropdown(app.Filtering_Panel);
            app.Filter_TypeofFilterDropDown.Items = {'Select Option', 'Low pass', 'High pass', 'Bandpass', 'Stop band'};
            app.Filter_TypeofFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_TypeofFilterDropDownValueChanged, true);
            app.Filter_TypeofFilterDropDown.Value = 'Select Option';
            app.SamplingAcquisitionLabel = uilabel(app.Filtering_Panel);
            app.SamplingAcquisitionLabel.Text = 'Sampling Acquisition';
            app.Filter_TypeofLFPsDropDown = uidropdown(app.Filtering_Panel);
            app.Filter_TypeofLFPsDropDown.Items = {'Select Option', '250Hz (Online Streaming)'};
            app.Filter_TypeofLFPsDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_TypeofLFPsDropDownValueChanged, true);
            app.Filter_TypeofLFPsDropDown.Value = 'Select Option';
            app.ApplyVisualizeButton = uibutton(app.Filtering_Panel, 'push');
            app.ApplyVisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyVisualizeButtonPushed, true);
            app.ApplyVisualizeButton.Enable = 'off';
            app.ApplyVisualizeButton.Text = 'Apply & Visualize ';
            app.VisualizeFiltersButton = uibutton(app.Filtering_Panel, 'push');
            app.VisualizeFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeFiltersButtonPushed, true);
            app.VisualizeFiltersButton.Enable = 'off';
            app.VisualizeFiltersButton.Text = 'Visualize filters';
            app.DeleteFiltersButton = uibutton(app.Filtering_Panel, 'push');
            app.DeleteFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFiltersButtonPushed, true);
            app.DeleteFiltersButton.Enable = 'off';
            app.DeleteFiltersButton.Text = 'Delete filters';
            app.FilterOrder4defaultLabel = uilabel(app.Filtering_Panel);
            app.FilterOrder4defaultLabel.Text = {'Filter Order'; '(4 default)'};
            app.FilterOrderSpinner = uispinner(app.Filtering_Panel);
            app.FilterOrderSpinner.Limits = [1 Inf];
            app.FilterOrderSpinner.Value = 4;
            app.ReadyLamp = uilamp(app.StreamingFilterWindowUIFigure);
            app.ReadyLamp_Label = uilabel(app.StreamingFilterWindowUIFigure);
            app.ReadyLamp_Label.Text = 'Ready';
            app.Label = uilabel(app.StreamingFilterWindowUIFigure);
            app.Label.Text = 'Neuroengineering and Computational Neuroscience, i3S 2023  |';
            app.Hyperlink = uihyperlink(app.StreamingFilterWindowUIFigure);
            app.Hyperlink.URL = 'https://github.com/NCN-Lab/DBScope';
            app.Hyperlink.Text = 'https://github.com/NCN-Lab/DBScope';
            % Show the figure after all components are created
            app.StreamingFilterWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilterData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StreamingFilterWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StreamingFilterWindowUIFigure)
        end
    end
end