classdef ScanArtifacts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScanArtifactsWindowUIFigure    matlab.ui.Figure
        Hyperlink                      matlab.ui.control.Hyperlink
        Label                          matlab.ui.control.Label
        Cardiac_BandPassFiltering_Panel  matlab.ui.container.Panel
        Filter_TypeofFilterDropDown    matlab.ui.control.DropDown
        TypeofFilterDropDownLabel      matlab.ui.control.Label
        Filter_PreviewButton           matlab.ui.control.Button
        Filter_UpperBoundHz_EditField  matlab.ui.control.NumericEditField
        UpperBoundHzEditFieldLabel     matlab.ui.control.Label
        Filter_LowerBoundHz_EditField  matlab.ui.control.NumericEditField
        LowerBoundHzEditFieldLabel     matlab.ui.control.Label
        ReadyLamp_Label                matlab.ui.control.Label
        ReadyLamp                      matlab.ui.control.Lamp
        Closeall_Button                matlab.ui.control.Button
        InspectforArtifactsLabel       matlab.ui.control.Label
        SystemInfo_Panel               matlab.ui.container.Panel
        Preprocess_ECGButton           matlab.ui.control.Button
        Preprocess_ApplyFFTButton      matlab.ui.control.Button
        Preprocess_Visualizetimedomaindata_Button  matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
    end

    properties (Access = private)
        obj % NCNPERCEPT objects
        current_patient
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, obj_arg, patient_arg)
            app.obj = obj_arg; % NCNPERCEPT objects argument from Main GUI
            app.current_patient = 1;
        end

        % Button pushed function: Closeall_Button
        function Closeall_ButtonPushed(app, event)
            all_fig = findall(0, 'type', 'figure');            
            delete(all_fig(~contains({all_fig.Name}, {'DBScope', 'Scan Artifacts Window', 'Streaming Filter Window'}))); 
        end

        % Button pushed function: Preprocess_Visualizetimedomaindata_Button
        function Preprocess_Visualizetimedomaindata_ButtonPushed(app, event)
            % Visualize Survey time domain data
            VisualizationWindow( app.obj, app.current_patient, "survey", 0 );
        end

        % Button pushed function: Preprocess_ApplyFFTButton
        function Preprocess_ApplyFFTButtonPushed(app, event)
           % Visualize Survey frequency domain data
            VisualizationWindow( app.obj, app.current_patient,  "survey_fft", 0 );
        end

        % Button pushed function: Preprocess_ECGButton
        function Preprocess_ECGButtonPushed(app, event)
            % Clear survey data from ECG artifacts
            for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                text = app.obj.ncnpercept_patient{app.current_patient}{file_indx}.survey_obj.cleanECGsurvey( 250 );
                dt = app.obj.ncnpercept_patient{app.current_patient}{file_indx}.parameters.session_date;
                f = msgbox(string([datestr(dt, 'yyyy-mmm-dd') ' Session']) + newline + text, "ECG Scan Results");
            end
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
        end

        % Value changed function: Filter_TypeofFilterDropDown
        function Filter_TypeofFilterDropDownValueChanged(app, event)
            app.Filter_LowerBoundHz_EditField.Enable = false;
            app.Filter_UpperBoundHz_EditField.Enable = false;

            % Select filter to apply
            switch app.Filter_TypeofFilterDropDown.Value
                case 'Low pass'
                    app.Filter_UpperBoundHz_EditField.Enable = true;
                case 'High pass'
                    app.Filter_LowerBoundHz_EditField.Enable = true;
                case 'Bandpass'
                    app.Filter_LowerBoundHz_EditField.Enable = true;
                    app.Filter_UpperBoundHz_EditField.Enable = true;
                case 'Stop band'
                    app.Filter_LowerBoundHz_EditField.Enable = true;
                    app.Filter_UpperBoundHz_EditField.Enable = true;
                otherwise
            end
        end

        % Value changed function: Filter_LowerBoundHz_EditField
        function Filter_LowerBoundHz_EditFieldValueChanged(app, event)
            value = app.Filter_LowerBoundHz_EditField.Value;
        end

        % Value changed function: Filter_UpperBoundHz_EditField
        function Filter_UpperBoundHz_EditFieldValueChanged(app, event)
            value = app.Filter_UpperBoundHz_EditField.Value;
        end

        % Button pushed function: Filter_PreviewButton
        function Filter_PreviewButtonPushed(app, event)
            app.ReadyLamp_Label.Text = 'Busy';
            app.ReadyLamp.Color = [0.64,0.08,0.18];
            drawnow;

            % Get filter lower and upper bound values
            lowb = app.Filter_LowerBoundHz_EditField.Value;
            upb  = app.Filter_UpperBoundHz_EditField.Value;

            % Apply selected filter
            switch app.Filter_TypeofFilterDropDown.Value
                case 'Low pass'
                    for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        status = app.obj.ncnpercept_patient{app.current_patient}{file_indx}.survey_obj.filtSurvey( 250, app.Filter_TypeofFilterDropDown.Value, 4, upb);
                    end
                case 'High pass'
                    for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        status = app.obj.ncnpercept_patient{app.current_patient}{file_indx}.survey_obj.filtSurvey( 250, app.Filter_TypeofFilterDropDown.Value, 4, lowb );
                    end
                case 'Bandpass'
                    for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        status = app.obj.ncnpercept_patient{app.current_patient}{file_indx}.survey_obj.filtSurvey(250, app.Filter_TypeofFilterDropDown.Value, 4, [lowb, upb] );
                    end
                case 'Stop band'
                    for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        status = app.obj.ncnpercept_patient{app.current_patient}{file_indx}.survey_obj.filtSurvey(250, app.Filter_TypeofFilterDropDown.Value, 4, [lowb, upb] );
                    end
                otherwise
            end

            if status
                VisualizationWindow( app.obj, app.current_patient, "survey_filt", 0 );
            end

            app.ReadyLamp_Label.Text = 'Ready';
            app.ReadyLamp.Color = [0.47,0.67,0.19];
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScanArtifactsWindowUIFigure = uifigure('Visible', 'off');
            app.ScanArtifactsWindowUIFigure.Name = 'Scan Artifacts Window';
            app.SystemInfo_Panel = uipanel(app.ScanArtifactsWindowUIFigure);
            app.SystemInfo_Panel.Title = 'Preprocessing';
            app.TextArea = uitextarea(app.SystemInfo_Panel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'This stage inspects for artifacts and filters the signal in the Survey mode. In an ECG artifact, a large amplitude signal is observable at the heart rate of 1-2Hz.  A motion artifact is characterized by a large amplitude signal, that is not periodic, during times when the patient moves.'};
            app.Preprocess_Visualizetimedomaindata_Button = uibutton(app.SystemInfo_Panel, 'push');
            app.Preprocess_Visualizetimedomaindata_Button.ButtonPushedFcn = createCallbackFcn(app, @Preprocess_Visualizetimedomaindata_ButtonPushed, true);
            app.Preprocess_Visualizetimedomaindata_Button.Text = '1. Visualize time domain data';
            app.Preprocess_ApplyFFTButton = uibutton(app.SystemInfo_Panel, 'push');
            app.Preprocess_ApplyFFTButton.ButtonPushedFcn = createCallbackFcn(app, @Preprocess_ApplyFFTButtonPushed, true);
            app.Preprocess_ApplyFFTButton.Text = '2. FFT';
            app.Preprocess_ECGButton = uibutton(app.SystemInfo_Panel, 'push');
            app.Preprocess_ECGButton.ButtonPushedFcn = createCallbackFcn(app, @Preprocess_ECGButtonPushed, true);
            app.Preprocess_ECGButton.Text = '3. ECG artifact scan';
            app.InspectforArtifactsLabel = uilabel(app.ScanArtifactsWindowUIFigure);
            app.InspectforArtifactsLabel.Text = 'Inspect for Artifacts';
            app.Closeall_Button = uibutton(app.ScanArtifactsWindowUIFigure, 'push');
            app.Closeall_Button.ButtonPushedFcn = createCallbackFcn(app, @Closeall_ButtonPushed, true);
            app.Closeall_Button.Text = 'Close all plots';
            app.ReadyLamp = uilamp(app.ScanArtifactsWindowUIFigure);
            app.ReadyLamp_Label = uilabel(app.ScanArtifactsWindowUIFigure);
            app.ReadyLamp_Label.Text = 'Ready';
            app.Cardiac_BandPassFiltering_Panel = uipanel(app.ScanArtifactsWindowUIFigure);
            app.Cardiac_BandPassFiltering_Panel.Title = 'Filtering';
            app.LowerBoundHzEditFieldLabel = uilabel(app.Cardiac_BandPassFiltering_Panel);
            app.LowerBoundHzEditFieldLabel.Text = 'Lower Bound [Hz]';
            app.Filter_LowerBoundHz_EditField = uieditfield(app.Cardiac_BandPassFiltering_Panel, 'numeric');
            app.Filter_LowerBoundHz_EditField.Limits = [0 125];
            app.Filter_LowerBoundHz_EditField.ValueChangedFcn = createCallbackFcn(app, @Filter_LowerBoundHz_EditFieldValueChanged, true);
            app.Filter_LowerBoundHz_EditField.Enable = 'off';
            app.UpperBoundHzEditFieldLabel = uilabel(app.Cardiac_BandPassFiltering_Panel);
            app.UpperBoundHzEditFieldLabel.Text = 'Upper Bound [Hz]';
            app.Filter_UpperBoundHz_EditField = uieditfield(app.Cardiac_BandPassFiltering_Panel, 'numeric');
            app.Filter_UpperBoundHz_EditField.Limits = [0 125];
            app.Filter_UpperBoundHz_EditField.ValueChangedFcn = createCallbackFcn(app, @Filter_UpperBoundHz_EditFieldValueChanged, true);
            app.Filter_UpperBoundHz_EditField.Enable = 'off';
            app.Filter_PreviewButton = uibutton(app.Cardiac_BandPassFiltering_Panel, 'push');
            app.Filter_PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @Filter_PreviewButtonPushed, true);
            app.Filter_PreviewButton.Text = 'Apply & Visualize';
            app.TypeofFilterDropDownLabel = uilabel(app.Cardiac_BandPassFiltering_Panel);
            app.TypeofFilterDropDownLabel.Text = 'Type of Filter';
            app.Filter_TypeofFilterDropDown = uidropdown(app.Cardiac_BandPassFiltering_Panel);
            app.Filter_TypeofFilterDropDown.Items = {'Select Option', 'Low pass', 'High pass', 'Bandpass', 'Stop band'};
            app.Filter_TypeofFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @Filter_TypeofFilterDropDownValueChanged, true);
            app.Filter_TypeofFilterDropDown.Value = 'Select Option';
            app.Label = uilabel(app.ScanArtifactsWindowUIFigure);
            app.Label.Text = 'Neuroengineering and Computational Neuroscience, i3S 2023  |';
            app.Hyperlink = uihyperlink(app.ScanArtifactsWindowUIFigure);
            app.Hyperlink.URL = 'https://github.com/NCN-Lab/DBScope';
            app.Hyperlink.Text = 'https://github.com/NCN-Lab/DBScope';
            % Show the figure after all components are created
            app.ScanArtifactsWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScanArtifacts(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScanArtifactsWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScanArtifactsWindowUIFigure)
        end
    end
end