classdef VisualizationWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisualizationWindowUIFigure     matlab.ui.Figure
        Hyperlink                       matlab.ui.control.Hyperlink
        Label                           matlab.ui.control.Label
        ReadyLamp_Label                 matlab.ui.control.Label
        ReadyLamp                       matlab.ui.control.Lamp
        Panel                           matlab.ui.container.Panel
        CompareAllEventsButton          matlab.ui.control.Button
        TypeSignalButtonGroup           matlab.ui.container.ButtonGroup
        ECGCleanedButton                matlab.ui.control.RadioButton
        LatestFilteredButton            matlab.ui.control.RadioButton
        RawButton                       matlab.ui.control.RadioButton
        Empty_Panel_right               matlab.ui.container.Panel
        Empty_Panel_bottom              matlab.ui.container.Panel
        Empty_Panel_left                matlab.ui.container.Panel
        Empty_Panel_top                 matlab.ui.container.Panel
        Interactivity_TabGroup          matlab.ui.container.TabGroup
        Empty_Tab                       matlab.ui.container.Tab
        ETA_Tab                         matlab.ui.container.Tab
        TimestampsSpinner               matlab.ui.control.Spinner
        NumberoftimestampsLabel         matlab.ui.control.Label
        EVNT_Tab                        matlab.ui.container.Tab
        EndingDateDatePicker            matlab.ui.control.DatePicker
        EndingDateDatePickerLabel       matlab.ui.control.Label
        StartingDateDatePicker          matlab.ui.control.DatePicker
        StartingDateDatePickerLabel     matlab.ui.control.Label
        TML_Tab                         matlab.ui.container.Tab
        UTCDropDown                     matlab.ui.control.DropDown
        UTCDropDownLabel                matlab.ui.control.Label
        SUM_Tab                         matlab.ui.container.Tab
        Scalogram_UpperBound            matlab.ui.control.NumericEditField
        Scalogram_LowerBound            matlab.ui.control.NumericEditField
        Scalogram_LimitsLabel           matlab.ui.control.Label
        Scalogram_DashBounds            matlab.ui.control.Label
        Spectrogram_ContrastLabel       matlab.ui.control.Label
        ContrastSwitch                  matlab.ui.control.Switch
        Tab7                            matlab.ui.container.Tab
        PlotBandpowerButton             matlab.ui.control.Button
        PowerButtonGroup                matlab.ui.container.ButtonGroup
        AllRecordingsButton             matlab.ui.control.RadioButton
        CurrentRecordingButton          matlab.ui.control.RadioButton
        AnalysisLabel                   matlab.ui.control.Label
        VisualizationLabel              matlab.ui.control.Label
        FrequencyBandButtonGroup        matlab.ui.container.ButtonGroup
        UpperBound                      matlab.ui.control.EditField
        DashBounds                      matlab.ui.control.Label
        LowerBound                      matlab.ui.control.EditField
        CustomButton                    matlab.ui.control.RadioButton
        BetaButton                      matlab.ui.control.RadioButton
        SelectWearableListBoxLabel      matlab.ui.control.Label
        SelecttypeofEventsListBoxLabel  matlab.ui.control.Label
        FileDropDown                    matlab.ui.control.DropDown
        SelectRecordingListBoxLabel     matlab.ui.control.Label
        Warning                         matlab.ui.control.Label
        Button_Next                     matlab.ui.control.Button
        Button_Previous                 matlab.ui.control.Button
        FieldSelectedLabel              matlab.ui.control.Label
        SelectFieldLabel                matlab.ui.control.Label
        SelectFieldListBox              matlab.ui.control.ListBox
        TabGroup                        matlab.ui.container.TabGroup
        Tab_1                           matlab.ui.container.Tab
        UIAxes1                         matlab.ui.control.UIAxes
        Tab_2                           matlab.ui.container.Tab
        UIAxes2_bottom                  matlab.ui.control.UIAxes
        UIAxes2_top                     matlab.ui.control.UIAxes
        Tab_3                           matlab.ui.container.Tab
        UIAxes3_topright                matlab.ui.control.UIAxes
        UIAxes3_topleft                 matlab.ui.control.UIAxes
        UIAxes3_bottom                  matlab.ui.control.UIAxes
        Tab_4                           matlab.ui.container.Tab
        Panel_topleft                   matlab.ui.container.Panel
        UIAxes4_topright                matlab.ui.control.UIAxes
        UIAxes4_bottomright             matlab.ui.control.UIAxes
        UIAxes4_bottomleft              matlab.ui.control.UIAxes
        UIAxes4_topleft                 matlab.ui.control.UIAxes
        Tab_5                           matlab.ui.container.Tab
        UIAxes5_bottom                  matlab.ui.control.UIAxes
        UIAxes5_middle                  matlab.ui.control.UIAxes
        UIAxes5_top                     matlab.ui.control.UIAxes
        Tab_6                           matlab.ui.container.Tab
        UIAxes6_left                    matlab.ui.control.UIAxes
        UIAxes6_right                   matlab.ui.control.UIAxes
        SelectWearableListBox           matlab.ui.control.ListBox
        SelecttypeofEventsListBox       matlab.ui.control.ListBox
        SelectRecordingListBox          matlab.ui.control.ListBox
        VisualizationWindowLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        obj % obj from class NCNPERCEPT_BATCH
        tab
        dcm
        status
        files_names
        items_names
        current_eventtype
        start_date
        end_date
        current_patient
        current_file
        current_indx
        current_recording
        current_wearable
        recording_limits
        filter_indx
        which_signal
        contrast
        utc
    end
    
    
    methods (Access = private)
        
        function getFieldItems(app)
            app.items_names = {};
            switch app.tab
                case "setup_off"
                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.setup_obj.setup_parameters;

                    % Get recordings and their corresponding items
                    num_recordings = length(temp_obj.stim_off.channel_names);
                    app.SelectRecordingListBox.Items = string(1:num_recordings);
                    app.current_recording = app.SelectRecordingListBox.Value;

                    % Get channel names from object
                    for c = 1:numel(temp_obj.stim_off.channel_names{str2double(app.current_recording)})
                        app.items_names{end+1} = [temp_obj.stim_off.channel_names{str2double(app.current_recording)}{c} ' ON'];
                    end
                    
                case "setup_on"
                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.setup_obj.setup_parameters;
                    
                    % Get recordings and their corresponding items
                    num_recordings = length(temp_obj.stim_on.channel_names);
                    app.SelectRecordingListBox.Items = string(1:num_recordings);
                    app.current_recording = app.SelectRecordingListBox.Value;

                    % Get channel names from object
                    for c = 1:numel(temp_obj.stim_on.channel_names{str2double(app.current_recording)})
                        app.items_names{end+1} = [temp_obj.stim_on.channel_names{str2double(app.current_recording)}{c} ' ON'];
                    end

                case "impedance"
                    % Get modes
                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.parameters.impedance;
                    modes = fieldnames(temp_obj.hemispheres(1));
                    modes = modes(~contains(modes, 'label'));
                    for c = 1:numel(modes)
                        app.items_names{end+1} = upper(modes{c});
                    end
                case "events_FFT"
                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.chronic_parameters;
                    for c = 1:length(temp_obj.events.date_time)
                        if ~isempty(temp_obj.events.lfp_frequency_snapshots_events{c,1}) && ...
                                strcmp( temp_obj.events.event_name{c}, app.current_eventtype ) && ...
                                temp_obj.events.date_time(c,:) >= app.start_date && ...
                                temp_obj.events.date_time(c,:) <= app.end_date + caldays(1)
                        
                        app.items_names{end+1} = datestr(temp_obj.events.date_time(c));
                        end
                    end
                    
                case "events_histogram"    
                    % Get events from object
                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.chronic_parameters;
                    for c = 1:length(temp_obj.events.date_time)
                        if strcmp( temp_obj.events.event_name{c}, app.current_eventtype ) && ...
                                temp_obj.events.date_time(c,:) >= app.start_date && ...
                                temp_obj.events.date_time(c,:) <= app.end_date + caldays(1)
                        app.items_names{end+1} = datestr(temp_obj.events.date_time(c) + hours(app.utc));
                        end
                    end

                case "ETA"
                    % Get events from object
                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.chronic_parameters;
                    events_timepoint = temp_obj.events.date_time(strcmp(temp_obj.events.event_name, app.current_eventtype));
                    for c = 1:length(events_timepoint)
                        app.items_names{end+1} = datestr(events_timepoint(c));
                    end
%                     app.items_names = {datestr(events_timepoint)};
                case "survey"
                    % Get recordings and their corresponding items
                    num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.survey_parameters.time_domain.channel_names);
                    app.SelectRecordingListBox.Items = string(1:num_recordings);
                    app.current_recording = app.SelectRecordingListBox.Value;

                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.survey_parameters;
                    temp_current = str2double(app.current_recording);
                    for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                        app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                    end

                case "indefinite"
                    % Get recordings and their corresponding items
                    num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.indefinite_obj.indefinite_parameters.time_domain.channel_names);

                    app.SelectRecordingListBox.Items = string(1:num_recordings);
                    app.current_recording = app.SelectRecordingListBox.Value;

                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.indefinite_obj.indefinite_parameters;
                    temp_current = str2double(app.current_recording);
                    for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                        app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                    end

                case "survey_fft"
                    % Get recordings and their corresponding items
                    num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.survey_parameters.time_domain.channel_names);

                    app.SelectRecordingListBox.Items = string(1:num_recordings);
                    app.current_recording = app.SelectRecordingListBox.Value;

                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.survey_parameters;
                    temp_current = str2double(app.current_recording);
                    for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                        app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                    end

                case "survey_filt"
                    % Get recordings and their corresponding items
                    num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.survey_parameters.time_domain.channel_names);

                    app.SelectRecordingListBox.Items = string(1:num_recordings);
                    app.current_recording = app.SelectRecordingListBox.Value;

                    temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.survey_parameters;
                    temp_current = str2double(app.current_recording);
                    for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                        app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                    end

                case "Stream summary"
                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                     temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters;
                     temp_current = str2double(app.current_recording);
                     for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                             app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                     end

                case "Stream Spectrogram"
                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);
    
                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;
    
                     temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters;
                     temp_current = str2double(app.current_recording);
                     for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                             app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                     end
    
                case "Stream Welch"
                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                     temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters;
                     temp_current = str2double(app.current_recording);
                     for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                             app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                     end

                case "Stream Wavelet"
                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                     temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters;
                     temp_current = str2double(app.current_recording);
                     for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                             app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                     end

                case "Stream AutoCorr"
                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                     temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters;
                     temp_current = str2double(app.current_recording);
                     for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                             app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                     end

                case "Stream CrossCorr"
                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                case "Stream Coherence"
                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                case "One Filter"
                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                     temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters;
                     temp_current = str2double(app.current_recording);
                     for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                             app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                     end

                case "One Filter indx"
                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                     temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters;
                     temp_current = str2double(app.current_recording);
                     for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                             app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                     end

                case "Multi Filter"
                    % Get recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                     temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters;
                     temp_current = str2double(app.current_recording);
                     for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                             app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                     end

                case "Wearable"
                    % Get LFP recordings and their corresponding items
                     num_recordings = length(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.channel_names);

                     app.SelectRecordingListBox.Items = string(1:num_recordings);
                     app.current_recording = app.SelectRecordingListBox.Value;

                     temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters;
                     temp_current = str2double(app.current_recording);
                     for c = 1:length(temp_obj.time_domain.channel_names{temp_current})
                             app.items_names{end+1} = temp_obj.time_domain.channel_names{temp_current}{c};
                     end

                otherwise
                    
            end
            
            app.SelectFieldListBox.Items = app.items_names;
            app.SelectFieldListBox.ItemsData = 1:numel(app.items_names);

        end

        
        function getEventsNames(app)
            events_names = {};
            temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj;
            
            % 1. Safety check: Do we even have type_events?
            if ~isempty(temp_obj.chronic_parameters.type_events)
                for evnt = 1:length(temp_obj.chronic_parameters.type_events)
                    curr_event_type = temp_obj.chronic_parameters.type_events{evnt};
                    
                    % Check if we haven't already added this event type
                    if ~any(strcmp(events_names, curr_event_type))
                        
                        % Find all indices where this event name occurs
                        idx_match = strcmp(temp_obj.chronic_parameters.events.event_name(:), curr_event_type);
                        
                        % Safely extract snapshots for this matched event
                        snapshots = temp_obj.chronic_parameters.events.lfp_frequency_snapshots_events(idx_match);
                        
                        % Check if there is at least one non-empty snapshot in this group
                        has_valid_snapshot = false;
                        if iscell(snapshots)
                            for k = 1:numel(snapshots)
                                % A snapshot is valid if it's not empty and not just NaN
                                if ~isempty(snapshots{k})
                                    has_valid_snapshot = true;
                                    break;
                                end
                            end
                        elseif ~isempty(snapshots)
                            has_valid_snapshot = true;
                        end
                        
                        % If it has valid data, add it to the UI list
                        if has_valid_snapshot
                            events_names{end+1} = curr_event_type; %#ok<AGROW>
                        end
                    end
                end
            end
            
            % 2. Safe UI Update
            if ~isempty(events_names)
                % Populate and enable the listbox
                app.SelecttypeofEventsListBox.Items = sort(events_names);
                app.SelecttypeofEventsListBox.Value = app.SelecttypeofEventsListBox.Items(1);
                app.current_eventtype = app.SelecttypeofEventsListBox.Value;
                app.SelecttypeofEventsListBox.Enable = 'on'; 
            else
                % Fallback state: Prevent the crash and inform the user
                app.SelecttypeofEventsListBox.Items = {'No FFT Events Available'};
                app.SelecttypeofEventsListBox.Value = app.SelecttypeofEventsListBox.Items(1);
                app.current_eventtype = ''; 
                app.SelecttypeofEventsListBox.Enable = 'off'; % Disable interaction
            end
        end

        function getEventsSnapshotNames(app)
            temp_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj;
            
            all_event_names = unique(temp_obj.chronic_parameters.events.event_name);
            
            has_snapshot = ~cellfun(@isempty, temp_obj.chronic_parameters.events.lfp_frequency_snapshots_events);
            
            valid_event_names = temp_obj.chronic_parameters.events.event_name(has_snapshot);
            events_names = unique(valid_event_names);
            
            missing_events = setdiff(all_event_names, events_names);
            
            if ~isempty(missing_events)
                
                msg = sprintf('The following event types lack FFT snapshots and will be excluded from the analysis:\n\n%s', ...
                    strjoin(missing_events, '\n'));
                
                uialert(app.VisualizationWindowUIFigure, msg, 'Missing Snapshots', 'Icon', 'warning');
            end
            
            if ~isempty(events_names)
                app.SelecttypeofEventsListBox.Items = sort(events_names);
                app.SelecttypeofEventsListBox.Value = app.SelecttypeofEventsListBox.Items{1};
                app.current_eventtype = app.SelecttypeofEventsListBox.Value;
            else
                app.SelecttypeofEventsListBox.Items = {'No Snapshots Available'};
                app.SelecttypeofEventsListBox.Value = 'No Snapshots Available';
                app.current_eventtype = ''; 
            end
        end

        function getWearablesNames(app)
            wearables_names     = {};
            wearables_id        = {};
            streaming_obj       = app.obj.ncnpercept_patient{ app.current_patient }{ app.current_file }.streaming_obj.streaming_parameters;
            streaming_first_dt  = streaming_obj.time_domain.first_packet_datetimes{ str2num(app.current_recording) };
            wearable_obj        = app.obj.ncnpercept_patient{ app.current_patient }{ app.current_file }.wearables_obj;
            
            start_dt = streaming_first_dt;
            end_dt = streaming_first_dt + seconds( streaming_obj.time_domain.time{ str2num(app.current_recording) }(end) ) ...
                - seconds( streaming_obj.time_domain.time{ str2num(app.current_recording) }(1) );

            app.recording_limits = [start_dt end_dt];

            for i = 1:numel( wearable_obj.data )
                if any( isbetween( wearable_obj.data( i ).time, start_dt, end_dt ) )
                    fields = fieldnames( wearable_obj.data( i ).data );
                    fields = fields( ~ismember( fields, ["Properties", "Row", "Variables"] ) );
                    for j = 1:numel(fields)
                        wearables_names{end + 1} = [char(wearable_obj.data( i ).wearable_id + " - " + fields(j))];
                        wearables_id{end+1} = [i, j];
                    end
                end

            end
            
            if ~isempty( wearables_names )
                [app.SelectWearableListBox.Items, indx] = sort(wearables_names);
                app.SelectWearableListBox.ItemsData     = wearables_id(indx);
                if isempty(app.current_wearable)
                    app.SelectWearableListBox.Value         = app.SelectWearableListBox.ItemsData{1};
                else
                    app.current_wearable                    = app.SelectWearableListBox.Value;
                end
            else
                app.SelectWearableListBox.Items = {};
            end
        end
       
        function axes = getAxesFromTab(app)
            switch app.TabGroup.SelectedTab
                case app.Tab_1
                    axes = [app.UIAxes1];
                case app.Tab_2
                    axes = [app.UIAxes2_top, app.UIAxes2_bottom];
                case app.Tab_3
                    axes = [app.UIAxes3_topleft, app.UIAxes3_topright, ...
                        app.UIAxes3_bottom];
                case app.Tab_4
                    axes = [app.UIAxes4_topleft, app.UIAxes4_bottomleft, ...
                            app.UIAxes4_topright, app.UIAxes4_bottomright];
                    if app.tab == "events_histogram"
                        app.Panel_topleft.Visible = true;
                        axes(1) = app.Panel_topleft;
                        app.UIAxes4_topleft.Visible = false;
                    end
                case app.Tab_5
                    axes = [app.UIAxes5_top, app.UIAxes5_middle, ...
                        app.UIAxes5_bottom];
                case app.Tab_6
                    axes = [app.UIAxes6_left, app.UIAxes6_right];
            end
        end
        
        function addAnalysisMenu(app)
            app.VisualizationWindowUIFigure.Position = [100,100,1050,680];            
            app.FrequencyBandButtonGroup.Visible = true;
            app.PowerButtonGroup.Visible = true;
            app.PlotBandpowerButton.Visible = true;
            app.AnalysisLabel.Visible = true;
        end
        
        function setLampBusy(app)
            app.ReadyLamp_Label.Text = "Busy";
            app.ReadyLamp.Color = [0.64,0.08,0.18];
            drawnow;          
        end

        function setLampReady(app)
            app.ReadyLamp_Label.Text = "Ready";
            app.ReadyLamp.Color = [0.4706 0.6706 0.1882];
            drawnow;            
        end
        
        function checkArtefactStatusStreaming(app)
            streaming_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj;
            if contains(streaming_obj.streaming_parameters.time_domain.artefact_status{str2double(app.current_recording)}{app.current_indx}, 'ECG')
                app.FieldSelectedLabel.BackgroundColor = [0.92,0.90,0.47];
            else
                app.FieldSelectedLabel.BackgroundColor = [0.70,0.84,0.90];
            end  
        end

        function checkArtefactStatusSurvey(app)
            aux_indx_artifact = (str2double(app.current_recording) - 1)* 6 + app.current_indx;
            survey_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj;
            if contains(survey_obj.survey_parameters.frequency_domain.artifact_status{aux_indx_artifact},'NOT')
                app.FieldSelectedLabel.BackgroundColor = [0.70,0.84,0.90]; 
            else
                app.FieldSelectedLabel.BackgroundColor = [0.92,0.90,0.47];
            end  
        end


        function removeVisualizationMenu(app)
            app.SelectFieldLabel.Visible        = false;
            app.SelectFieldListBox.Visible      = false;
            app.FieldSelectedLabel.Visible      = false;
            app.Button_Next.Visible             = false;
            app.Button_Previous.Visible         = false;
            app.VisualizationLabel.Visible      = false;

            app.Interactivity_TabGroup.Visible  = false;
            app.Empty_Panel_left.Visible        = false;
            app.Empty_Panel_top.Visible         = false;
            app.Empty_Panel_right.Visible       = false;
            app.Empty_Panel_bottom.Visible      = false;

            % How to adjust position
            shift = 125;
            pos = app.TabGroup.Position;
            pos(3) = pos(3) + shift;
            app.TabGroup.Position = pos;
            pos = app.FileDropDown.Position;
            pos(3) = pos(3) + shift;
            app.FileDropDown.Position = pos;
            pos = app.UIAxes2_bottom.Position;
            pos(3) = pos(3) + shift;
            app.UIAxes2_bottom.Position = pos;
            pos = app.UIAxes2_top.Position;
            pos(3) = pos(3) + shift;
            app.UIAxes2_top.Position = pos;
        end
        
        function alignAxes(app, axes)
            switch numel(axes)
                case 2
                    set(axes(2), 'PositionConstraint', 'innerposition');
                    set(axes(1), 'PositionConstraint', 'innerposition');
                    pos2 = get(axes(2), 'InnerPosition');
                    pos1 = get(axes(1), 'InnerPosition');

                    if pos1(3) ~= pos2(3)
                        pos1(1) = pos2(1);
                        pos1(3) = pos2(3);
                        set(axes(1),'InnerPosition', pos1);
                    end
                    linkaxes(axes, 'x');
                    
                case 3
                    set(axes(3), 'PositionConstraint', 'innerposition');
                    set(axes(2), 'PositionConstraint', 'innerposition');
                    set(axes(1), 'PositionConstraint', 'innerposition');
                    pos3 = get(axes(3), 'InnerPosition');
                    pos2 = get(axes(2), 'InnerPosition');
                    pos1 = get(axes(1), 'InnerPosition');

                    if pos1(3) ~= pos2(3)
                        pos1(1) = pos2(1);
                        pos1(3) = pos2(3);
                        pos3(1) = pos2(1);
                        pos3(3) = pos2(3);
                        set(axes(1),'InnerPosition', pos1);
                        set(axes(3),'InnerPosition', pos3);
                    end
                    linkaxes(axes, 'x');
            end
        end
        
        function addRecordingList(app)
            app.SelectRecordingListBox.Visible = true;
            app.SelectRecordingListBoxLabel.Visible = true;
        end
        
        function addEventsList(app)
            app.SelecttypeofEventsListBox.Visible = true;
            app.SelecttypeofEventsListBoxLabel.Visible = true;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, obj_arg, patient_arg, tab_arg, filter_indx_arg)
            app.obj = obj_arg; % NCNPERCEPT objects argument from Main GUI
            app.tab = tab_arg;
            app.filter_indx = filter_indx_arg;
            app.current_patient = patient_arg;
            app.current_file = 1;
            app.current_indx = 1;
            app.status = zeros(length(app.obj.ncnpercept_patient{app.current_patient}),1);
            app.Button_Previous.Enable = false;
            app.utc = str2double(app.UTCDropDown.Value);
            app.contrast = "normal";

            app.Interactivity_TabGroup.SelectedTab = app.Empty_Tab;
            
            % ============================================================
            % Default size (only visualization menu)
            app.VisualizationWindowUIFigure.Position = [100,100,880,680];
            % ============================================================

            app.dcm = datacursormode(app.VisualizationWindowUIFigure);
            app.dcm.Enable = 'on';
            warning('off', 'MATLAB:modes:mode:InvalidPropertySet');
            warning('off', 'MATLAB:Axes:NegativeLimitsInLogAxis');
            
            app.files_names = {};
            for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                dt = app.obj.ncnpercept_patient{app.current_patient}{file}.parameters.session_date;
                app.files_names{end+1} = string(datetime(dt,'Format','yyyy-MMM-dd ''Session'''));
            end            
            
            switch app.tab
                case "setup_off"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Setup OFF" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    app.addRecordingList();

                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.setup_off;
                    end
                    app.current_file = find(app.status==1, 1);
                    app.FileDropDown.Items = cellstr(app.files_names);
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);
                    
                    if ~isempty(app.current_file)app.FileDropDown.Items = cellstr(app.files_names);
                        app.getFieldItems;
                        app.FieldSelectedLabel.Text = app.items_names{app.current_indx};                        
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.setup_obj.plotRawDataSetupOFF( axes(1), str2double(app.current_recording), app.current_indx );
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.setup_obj.plotFFTSetupOFF( axes(2), str2double(app.current_recording), app.current_indx );
                    end
                    
                case "setup_on"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Setup ON" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    app.addRecordingList();

                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.setup_on;
                    end
                    app.current_file = find(app.status==1, 1);
                    app.FileDropDown.Items = cellstr(app.files_names);
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);
                    
                    if ~isempty(app.current_file)
                        app.getFieldItems;
                        app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.setup_obj.plotRawDataSetupON( axes(1), str2double(app.current_recording), app.current_indx );
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.setup_obj.plotFFTSetupON( axes(2), str2double(app.current_recording), app.current_indx );
                    end
                case "impedance"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Impedance Tests" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.impedance;
                    end
                    app.current_file = find(app.status==1, 1);
                    app.FileDropDown.Items = cellstr(app.files_names);
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);
                    
                    if ~isempty(app.current_file)
                        app.getFieldItems;
                        app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.plotImpedance( axes, app.items_names{app.current_indx} );
                    end

                case "chronic"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Timeline" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    app.removeVisualizationMenu();

                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.chronic;
                    end
                    app.current_file = find(app.status==1, 1);
                    app.FileDropDown.Items = cellstr(app.files_names);
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);

                    if ~isempty(app.current_file)
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotLFPTrendLogs( axes, app.utc );
                    end
                    
                case "chronic_auto"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Chronic Auto" ;
                    app.TabGroup.SelectedTab = app.Tab_4;
                    axes = app.getAxesFromTab();
                    app.removeVisualizationMenu();
                    
                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.events;
                    end
                    app.current_file = find(app.status==1, 1);
                    app.FileDropDown.Items = cellstr(app.files_names);
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);
                    
                    if ~isempty(app.current_file)
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotAutoCorrVarChronic( ...
                            axes );                        
                    end
                    
                case "chronic_cross"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Chronic Cross" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    app.removeVisualizationMenu();
                    
                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.events;
                    end
                    app.current_file = find(app.status==1, 1);
                    app.FileDropDown.Items = cellstr(app.files_names);
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);
                    
                    if ~isempty(app.current_file)
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotCrossCorrVarChronic( ...
                            axes );
                    end

                case "circadian"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Circadian Rhythm" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    app.removeVisualizationMenu();

                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.chronic;
                    end
                    app.current_file = find(app.status==1, 1);
                    app.FileDropDown.Items = cellstr(app.files_names);
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);

                    if ~isempty(app.current_file)
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotCircadian( axes, app.utc );
                    end

                case "events_FFT"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Events FFT" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();                    
                    app.addEventsList();
                    app.CompareAllEventsButton.Visible = true;
                    app.Interactivity_TabGroup.SelectedTab = app.EVNT_Tab;
                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.events_FFT;
                    end
                    app.current_file = find(app.status==1, 1);
                    app.FileDropDown.Items = cellstr(app.files_names);
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);
                    app.start_date = datetime(1900, 1, 1);
                    app.end_date = datetime('now', 'Format','d-MMM-y');
                    
                    if ~isempty(app.current_file)
                        
                        app.getEventsSnapshotNames(); 
                        
                        if ~isempty(app.current_eventtype)
                            app.getFieldItems();
                            if ~isempty(app.items_names)
                                app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                            end                        
                            
                            app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotMeanFFTProfile( ...
                                axes, app.current_eventtype, [app.start_date app.end_date]);
                            
                            if ~isempty(app.items_names)
                                app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotSelectedFFTProfile( ...
                                    axes, app.items_names{app.current_indx} );
                            end
                        end
                    end
                    
                case "events_histogram"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Events Information" ;
                    app.TabGroup.SelectedTab = app.Tab_4;
                    axes = app.getAxesFromTab();
                    
                    % Transform axes into polar axes
                    % app.UIAxes4_topleft, app.UIAxes4_bottomleft, ...
                    %          app.UIAxes4_topright, app.UIAxes4_bottomright
                    % axes(1) = app.Panel_topleft;

                    app.addEventsList();
                    app.Interactivity_TabGroup.SelectedTab = app.EVNT_Tab;

                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.events;
                    end
                    app.current_file = find(app.status==1, 1);
                    app.FileDropDown.Items = cellstr(app.files_names);
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);

                    app.start_date = datetime(1900, 1, 1);
                    app.end_date = datetime('now', 'Format','d-MMM-y');

                    if ~isempty(app.current_file)
                        app.getEventsNames;
                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotEventHistograms( ...
                            app.items_names{app.current_indx}, app.current_eventtype, axes, app.utc );
                    end
                    
                case "ETA"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Event-Triggered Average" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    app.addEventsList();
                    app.Interactivity_TabGroup.SelectedTab = app.ETA_Tab;

                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.events && ...
                            app.obj.ncnpercept_patient{app.current_patient}{file}.status.chronic;
                    end
                    app.current_file = find(app.status==1, 1);
                    app.FileDropDown.Items = cellstr(app.files_names);
                    app.FileDropDown.ItemsData = 1:numel(app.files_names);
                    
                    if ~isempty(app.current_file)
                        app.getEventsNames;
                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotETA( ...
                            axes, app.TimestampsSpinner.Value, app.current_eventtype, app.items_names{app.current_indx});
                    end

                case "survey"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Survey" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();                    
                    app.addRecordingList();

                    % Display types of recordings available
                    if isempty(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}. ...
                            survey_obj.survey_parameters.filtered_data.data)
                        app.LatestFilteredButton.Visible = false;
                    end
                    app.ECGCleanedButton.Visible = false;
                    app.TypeSignalButtonGroup.Visible = true;
                    app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text; % Raw (default)

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.survey;
                    end

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            aux_indx_artifact = (str2double(app.current_recording) - 1)* 6 + app.current_indx; 

                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx} + ...
                                " - " + app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.survey_parameters.frequency_domain.artifact_status{aux_indx_artifact};

                            app.checkArtefactStatusSurvey();

                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = cellstr(app.files_names);
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.plotRawDataSurvey( axes(1), str2double(app.current_recording), app.current_indx, 'DataType', app.which_signal );
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.plotFFTSurvey( axes(2), str2double(app.current_recording), app.current_indx, 'DataType', app.which_signal );

                     end

                case "indefinite"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Indefinite" ;
                    app.TabGroup.SelectedTab = app.Tab_1;
                    axes = app.getAxesFromTab();                    
                    app.addRecordingList();

                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.indefinite;
                    end

                    if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = cellstr(app.files_names);
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        app.which_signal = 'Raw';

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.indefinite_obj.plotRawDataSurveyInd( axes, str2double(app.current_recording), app.current_indx );
                    end

                case "survey_fft"

                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Survey" ;
                    app.TabGroup.SelectedTab = app.Tab_1;
                    axes = app.getAxesFromTab();
                    app.addRecordingList();

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.survey;
                    end

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            aux_indx_artifact = (str2double(app.current_recording) - 1)* 6 + app.current_indx; 

                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx} + ...
                                " - " + app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.survey_parameters.frequency_domain.artifact_status{aux_indx_artifact};

                            app.checkArtefactStatusSurvey();
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = cellstr(app.files_names);
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.plotFFTSurvey( axes, str2double(app.current_recording), app.current_indx );
                     end

                case "survey_filt"

                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Survey" ;
                    app.TabGroup.SelectedTab = app.Tab_4;
                    axes = app.getAxesFromTab();                    
                    app.addRecordingList();
                    app.AnalysisLabel.Visible = true;

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.survey;
                    end

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = cellstr(app.files_names);
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.plotFiltDataSurvey( axes, str2double(app.current_recording), app.current_indx );

                     end

                case "Stream summary" 
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_5;
                    axes = app.getAxesFromTab();
                    app.addAnalysisMenu();                    
                    app.addRecordingList();
                    app.Interactivity_TabGroup.SelectedTab = app.SUM_Tab;

                    streaming_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj;

                    % Display types of recordings available
                    if isempty(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}. ...
                            streaming_obj.streaming_parameters.filtered_data.data)
                        app.LatestFilteredButton.Visible = false;
                        app.ECGCleanedButton.Position = [11,19,85,15];
                    end
                    app.TypeSignalButtonGroup.Visible = true;
                    app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text; % Raw (default)

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx} + ...
                                " - " + streaming_obj.streaming_parameters.time_domain.artefact_status{str2double(app.current_recording)}{app.current_indx};
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = cellstr(app.files_names(app.status==1));
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        streaming_obj.plotSignalAndStimulation( axes(1), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal );
                        streaming_obj.plotSpectrogram( axes(2), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal, 'Contrast', app.contrast );
                        streaming_obj.plotScalogram( axes(3), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal );
                        
                        app.alignAxes(axes);
                     end

                case "Stream Spectrogram"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    app.addAnalysisMenu();                    
                    app.addRecordingList();
                    app.Interactivity_TabGroup.SelectedTab = app.SUM_Tab;
                    app.Scalogram_LowerBound.Visible = false;
                    app.Scalogram_UpperBound.Visible = false;
                    app.Scalogram_DashBounds.Visible = false;
                    app.Scalogram_LimitsLabel.Visible = false;
                    
                    streaming_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj;

                    % Display types of recordings available
                    if isempty(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}. ...
                            streaming_obj.streaming_parameters.filtered_data.data)
                        app.LatestFilteredButton.Visible = false;
                        app.ECGCleanedButton.Position = [11,19,85,15];
                    end
                    app.TypeSignalButtonGroup.Visible = true;
                    app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text; % Raw (default)

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            if isfield(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain, "artefact_status")
                                app.FieldSelectedLabel.Text = app.FieldSelectedLabel.Text + ...
                                        " - " + app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.artefact_status{str2double(app.current_recording)}{app.current_indx};
                                app.checkArtefactStatusStreaming();
                            end
                        end                      

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = cellstr(app.files_names(app.status==1));
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);
                        
                        streaming_obj.plotSignalAndStimulation( axes(1), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal );
                        streaming_obj.plotSpectrogram( axes(2), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal, 'Contrast', app.contrast );
                        
                        app.alignAxes(axes);
                     end

                case "Stream Welch"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_1;
                    axes = app.getAxesFromTab();                    
                    app.addRecordingList();

                    streaming_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj;

                    % Display types of recordings available
                    if isempty(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}. ...
                            streaming_obj.streaming_parameters.filtered_data.data)
                        app.LatestFilteredButton.Visible = false;
                        app.ECGCleanedButton.Position = [11,19,85,15];
                    end
                    app.TypeSignalButtonGroup.Visible = true;
                    app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text; % Raw (default)

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            if isfield(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain, "artefact_status")
                                app.FieldSelectedLabel.Text = app.FieldSelectedLabel.Text + ...
                                        " - " + app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.artefact_status{str2double(app.current_recording)}{app.current_indx};
                                app.checkArtefactStatusStreaming();
                            end
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = cellstr(app.files_names(app.status==1));
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        streaming_obj.plotPWelch( axes, app.which_signal, str2double(app.current_recording), app.current_indx );

                     end

                case "Stream Wavelet"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    app.addAnalysisMenu();                    
                    app.addRecordingList();
                    app.Interactivity_TabGroup.SelectedTab = app.SUM_Tab;
                    app.ContrastSwitch.Visible = false;
                    app.Spectrogram_ContrastLabel.Visible = false;

                    streaming_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj;

                    % Display types of recordings available
                    if isempty(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}. ...
                            streaming_obj.streaming_parameters.filtered_data.data)
                        app.LatestFilteredButton.Visible = false;
                        app.ECGCleanedButton.Position = [11,19,85,15];
                    end
                    app.TypeSignalButtonGroup.Visible = true;
                    app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text; % Raw (default)

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            if isfield(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain, "artefact_status")
                                app.FieldSelectedLabel.Text = app.FieldSelectedLabel.Text + ...
                                        " - " + app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.artefact_status{str2double(app.current_recording)}{app.current_indx};
                                app.checkArtefactStatusStreaming();
                            end
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = cellstr(app.files_names(app.status==1));
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);
                        
                        streaming_obj.plotSignalAndStimulation( axes(1), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal );
                        streaming_obj.plotScalogram( axes(2), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal );
                        
                        app.alignAxes(axes);
                     end

                case "Stream AutoCorr"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;


                    streaming_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj;

                    % Display types of recordings available
                    if isempty(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}. ...
                            streaming_obj.streaming_parameters.filtered_data.data)
                        app.LatestFilteredButton.Visible = false;
                        app.ECGCleanedButton.Position = [11,19,85,15];
                    end
                    app.TypeSignalButtonGroup.Visible = true;
                    app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text; % Raw (default)

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            if isfield(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain, "artefact_status")
                                app.FieldSelectedLabel.Text = app.FieldSelectedLabel.Text + ...
                                        " - " + app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.artefact_status{str2double(app.current_recording)}{app.current_indx};
                                app.checkArtefactStatusStreaming();
                            end
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = cellstr(app.files_names(app.status==1));
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        streaming_obj.plotAutoCorrVar( axes, app.which_signal, str2double(app.current_recording), app.current_indx );
                     end

                case "Stream CrossCorr"
      
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    axes = app.getAxesFromTab();
                    
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;
                    app.SelectFieldListBox.Visible = false;
                    app.SelectFieldLabel.Visible = false;
                    app.FieldSelectedLabel.Visible = false;
                    app.Button_Next.Visible = false;
                    app.Button_Previous.Visible = false;
                    app.VisualizationLabel.Visible = false;


                    streaming_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj;

                    % Display types of recordings available
                    if isempty(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}. ...
                            streaming_obj.streaming_parameters.filtered_data.data)
                        app.LatestFilteredButton.Visible = false;
                        app.ECGCleanedButton.Position = [11,19,85,15];
                    end
                    app.TypeSignalButtonGroup.Visible = true;
                    app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text; % Raw (default)

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                     if ~isempty(app.current_file)

                         app.getFieldItems;

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = cellstr(app.files_names(app.status==1));
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        streaming_obj.plotCrossCorrVar( axes, app.which_signal, str2double(app.current_recording) );
                     end

                case "Stream Coherence" 
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_6;
                    axes = app.getAxesFromTab();
                    
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;
                    app.SelectFieldListBox.Visible = false;
                    app.SelectFieldLabel.Visible = false;
                    app.FieldSelectedLabel.Visible = false;
                    app.Button_Next.Visible = false;
                    app.Button_Previous.Visible = false;
                    app.VisualizationLabel.Visible = false;

                    app.LatestFilteredButton.Visible = false;
                    app.ECGCleanedButton.Position = [11,19,85,15];
                    app.TypeSignalButtonGroup.Visible = true;
                    app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text; % Raw (default)

                    streaming_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj;

                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end

                    app.current_file = find(app.status==1, 1);

                    if ~isempty(app.current_file)

                        app.getFieldItems;

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = cellstr(app.files_names(app.status==1));
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);
                        
                        streaming_obj.plotCoherence_band( axes(1), app.which_signal, str2double(app.current_recording) );
                        % view( axes(1), [-90 90] );
                        streaming_obj.plotCoherence( axes(2), app.which_signal, str2double(app.current_recording) );
                        ylim( axes(1), get(axes(2), 'YLim' ) );
                        linkaxes(axes, 'y');
                    end

                case "One Filter"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_4;
                    axes = app.getAxesFromTab();
                    
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;

                    app.Interactivity_TabGroup.SelectedTab = app.SUM_Tab;
                    app.Scalogram_LowerBound.Visible = false;
                    app.Scalogram_UpperBound.Visible = false;
                    app.Scalogram_DashBounds.Visible = false;
                    app.Scalogram_LimitsLabel.Visible = false;

                    streaming_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj;

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = cellstr(app.files_names(app.status==1));
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        streaming_obj.plotFiltDataStream( -1, axes, str2double(app.current_recording), app.items_names{app.current_indx}, app.contrast );

                     end

                case "One Filter indx"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_4;
                    axes = app.getAxesFromTab();
                    
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;


                    streaming_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj;

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = cellstr(app.files_names(app.status==1));
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        streaming_obj.plotFiltDataStream( app.filter_indx, axes, str2double(app.current_recording), app.items_names{app.current_indx}, app.contrast );

                     end

                case "Multi Filter" 
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Streaming" ;
                    app.TabGroup.SelectedTab = app.Tab_5;
                    axes = app.getAxesFromTab();
                    
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;


                    streaming_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj;

                     for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                     if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        end

                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.FileDropDown.Items = cellstr(app.files_names(app.status==1));
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        streaming_obj.plotFiltDataStream_multi( app.filter_indx, axes, str2double(app.current_recording), app.current_indx );
                     end 


                case "Wearable"
                    app.VisualizationWindowLabel.Text = app.obj.patient_list(app.current_patient) + " - Wearable" ;
                    app.TabGroup.SelectedTab = app.Tab_5;
                    axes = app.getAxesFromTab();
                    app.addAnalysisMenu();
                    pos = app.SelectFieldListBox.Position;
                    pos(2) = pos(2) + 125;
                    pos(4) = pos(4) - 125;
                    app.SelectFieldListBox.Position = pos;
                    pos = app.SelectRecordingListBox.Position;
                    pos(2) = pos(2) + 125;
                    app.SelectRecordingListBox.Position = pos;
                    pos = app.SelectRecordingListBoxLabel.Position;
                    pos(2) = pos(2) + 125;
                    app.SelectRecordingListBoxLabel.Position = pos;
                    app.SelectRecordingListBox.Visible = true;
                    app.SelectRecordingListBoxLabel.Visible = true;
                    app.SelectWearableListBox.Visible = true;
                    app.SelectWearableListBoxLabel.Visible = true;
                    
                    app.Interactivity_TabGroup.SelectedTab = app.SUM_Tab;
                    app.ContrastSwitch.Visible = false;
                    app.Spectrogram_ContrastLabel.Visible = false;


                    streaming_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj;

                    % Display types of recordings available
                    if isempty(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}. ...
                            streaming_obj.streaming_parameters.filtered_data.data)
                        app.LatestFilteredButton.Visible = false;
                        app.ECGCleanedButton.Position = [11,19,85,15];
                    end
                    app.TypeSignalButtonGroup.Visible = true;
                    app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text; % Raw (default)

                    for file = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.status(file) = app.obj.ncnpercept_patient{app.current_patient}{file}.status.streaming;
                    end
                    app.current_file = find(app.status==1, 1);

                    if ~isempty(app.current_file)

                        app.getFieldItems;
                        if ~isempty(app.items_names)
                            if isfield(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain, "artefact_status")
                                app.FieldSelectedLabel.Text = app.FieldSelectedLabel.Text + ...
                                        " - " + app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.artefact_status{str2double(app.current_recording)}{app.current_indx};
                                app.checkArtefactStatusStreaming();
                            end
                        end
                        app.current_recording = app.SelectRecordingListBox.Value;

                        app.getWearablesNames;

                        app.FileDropDown.Items = cellstr(app.files_names(app.status==1));
                        aux_file_indx = 1:numel(app.files_names);
                        app.FileDropDown.ItemsData = aux_file_indx(app.status==1);

                        streaming_obj.plotSignalAndStimulation( axes(1), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal );
                        streaming_obj.plotScalogram( axes(2), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal );
                        if ~isempty( app.SelectWearableListBox.Items )
                            axes(3).Visible = true;
                            
                            app.current_wearable = app.SelectWearableListBox.Value;
                            app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.wearables_obj.plotWearable( axes(3), app.current_wearable(1), app.current_wearable(2), 'Limits', app.recording_limits );
                        else
                            cla( axes(3), "reset" );
                            axes(3).Visible = false;
                        end

                        app.alignAxes(axes);
                    end
                    
                otherwise
                    
            end
            
        end

        % Value changed function: SelectFieldListBox
        function SelectFieldListBoxValueChanged(app, event)
            app.setLampBusy();
            
            if app.SelectFieldListBox.Visible
                app.Button_Previous.Enable = true;
                app.Button_Next.Enable = true;
                app.current_indx = app.SelectFieldListBox.Value;
                if app.current_indx == length(app.items_names)
                    app.Button_Next.Enable = false;
                end
                if app.current_indx == 1
                    app.Button_Previous.Enable = false;
                end
                if contains(app.tab, "Stream") || contains(app.tab, "Wearable")
                    app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                    if isfield(app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain, "artefact_status")
                        app.FieldSelectedLabel.Text = app.FieldSelectedLabel.Text + ...
                                " - " + app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.streaming_parameters.time_domain.artefact_status{str2double(app.current_recording)}{app.current_indx};
                        app.checkArtefactStatusStreaming();
                    end
                elseif contains(app.tab,"survey")
                    aux_indx_artifact = (str2double(app.current_recording) - 1)* 6 + app.current_indx; 

                    app.FieldSelectedLabel.Text = app.items_names{app.current_indx} + ...
                                " - " + app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.survey_parameters.frequency_domain.artifact_status{aux_indx_artifact};
                    app.checkArtefactStatusSurvey();
                else
                    app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                end
            end
            drawnow;
            axes = app.getAxesFromTab();
            switch app.tab
                case "setup_off"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.setup_obj.plotRawDataSetupOFF( axes(1), str2double(app.current_recording), app.current_indx );
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.setup_obj.plotFFTSetupOFF( axes(2), str2double(app.current_recording), app.current_indx );
                case "setup_on"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.setup_obj.plotRawDataSetupON( axes(1), str2double(app.current_recording), app.current_indx );
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.setup_obj.plotFFTSetupON( axes(2), str2double(app.current_recording), app.current_indx );
                case "impedance"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.plotImpedance( [app.UIAxes2_top, app.UIAxes2_bottom], app.items_names{app.current_indx} );
                case "indefinite"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.indefinite_obj.plotRawDataSurveyInd( app.UIAxes1, str2double(app.current_recording), app.current_indx );
                case "events_FFT"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotSelectedFFTProfile( ...
                        [app.UIAxes2_top, app.UIAxes2_bottom], app.items_names{app.current_indx} );
                case "events_histogram"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotEventHistograms( ...
                            app.items_names{app.current_indx}, app.current_eventtype,  axes, app.utc );

                case "chronic"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotLFPTrendLogs( axes, app.utc );

                    return;
                case "chronic_auto"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotAutoCorrVarChronic( ...
                        axes );

                case "chronic_cross"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotCrossCorrVarChronic( ...
                        axes );

                case "circadian"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotCircadian( axes, app.utc );

                case "ETA"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotETA( ...
                            [app.UIAxes2_top, app.UIAxes2_bottom], app.TimestampsSpinner.Value, app.current_eventtype, app.items_names{app.current_indx} );

                case "survey"
                    % app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.plotRawDataLFPMontage( axes, app.which_signal, str2double(app.current_recording), app.current_indx );
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.plotRawDataSurvey( axes(1), str2double(app.current_recording), app.current_indx, 'DataType', app.which_signal );
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.plotFFTSurvey( axes(2), str2double(app.current_recording), app.current_indx, 'DataType', app.which_signal );

                    app.checkArtefactStatusSurvey();

                case "survey_fft"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.plotFFTSurvey( app.UIAxes1, str2double(app.current_recording), app.current_indx );

                case "survey_filt"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.survey_obj.plotFiltDataSurvey( axes, str2double(app.current_recording), app.current_indx );

                case "Stream summary"
                    lower_bound = app.Scalogram_LowerBound.Value;
                    upper_bound = app.Scalogram_UpperBound.Value;
                    streaming_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj;
                    streaming_obj.plotSignalAndStimulation( axes(1), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal );
                    streaming_obj.plotSpectrogram( axes(2), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal, 'Contrast', app.contrast );
                    streaming_obj.plotScalogram( axes(3), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal, 'Limits', [lower_bound upper_bound] );
                    linkaxes(axes, 'x');
                case "Stream Spectrogram"
                    streaming_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj;
                    streaming_obj.plotSignalAndStimulation( axes(1), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal );
                    streaming_obj.plotSpectrogram( axes(2), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal, 'Contrast', app.contrast );
                    linkaxes(axes, 'x');
                case "Stream Welch"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotPWelch( axes, app.which_signal, str2double(app.current_recording), app.current_indx );

                case "Stream Wavelet"
                    lower_bound = app.Scalogram_LowerBound.Value;
                    upper_bound = app.Scalogram_UpperBound.Value;
                    streaming_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj;
                    streaming_obj.plotSignalAndStimulation( axes(1), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal );
                    streaming_obj.plotScalogram( axes(2), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal, 'Limits', [lower_bound upper_bound] );
                    linkaxes(axes, 'x');
                case "Stream AutoCorr"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotAutoCorrVar( axes, app.which_signal, str2double(app.current_recording), app.current_indx );

                case "Stream CrossCorr"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotCrossCorrVar( axes, app.which_signal, str2double(app.current_recording) );
                
                case "Stream Coherence"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotCoherence_band( axes(1), app.which_signal, str2double(app.current_recording) );
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotCoherence( axes(2), app.which_signal, str2double(app.current_recording) );
                    ylim( axes(1), get(axes(2), 'YLim' ) );
                    linkaxes(axes, 'y');

                case "One Filter"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotFiltDataStream( -1, axes, str2double(app.current_recording), app.items_names{app.current_indx}, app.contrast );

                case "One Filter indx"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotFiltDataStream( app.filter_indx, axes, str2double(app.current_recording), app.current_indx );

                case "Multi Filter"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotFiltDataStream_multi( app.filter_indx, axes, str2double(app.current_recording), app.current_indx );

                case "Wearable"
                    app.getWearablesNames();
                    lower_bound = app.Scalogram_LowerBound.Value;
                    upper_bound = app.Scalogram_UpperBound.Value;
                    streaming_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj;
                    streaming_obj.plotSignalAndStimulation( axes(1), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal );
                    streaming_obj.plotScalogram( axes(2), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal, 'Limits', [lower_bound upper_bound] );
                    if ~isempty( app.SelectWearableListBox.Items )
                        axes(3).Visible = true;
                        app.current_wearable = app.SelectWearableListBox.Value;
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.wearables_obj.plotWearable( axes(3), app.current_wearable(1), app.current_wearable(2), 'Limits', app.recording_limits );
                    else
                        cla( axes(3), "reset" );
                        axes(3).Visible = false;
                    end

                    app.alignAxes(axes);
                otherwise

            end

            app.setLampReady();
        end

        % Button pushed function: Button_Next
        function Button_NextPushed(app, event)
            
            % 1. Count how many items are currently in the list
            total_items = length(app.SelectFieldListBox.Items);
            
            % 2. Check if the NEXT index is within safe bounds
            if (app.current_indx + 1) <= total_items
                
                % It is safe! Move forward.
                app.SelectFieldListBox.Value = app.current_indx + 1;
                
                % Trigger the update callback
                app.SelectFieldListBoxValueChanged;
            end
        end

        % Button pushed function: Button_Previous
        function Button_PreviousPushed(app, event)
            % Check if the PREVIOUS index is within safe bounds
            if (app.current_indx - 1) >= 1
                
                % It is safe! Move backward.
                app.SelectFieldListBox.Value = app.current_indx - 1;
                
                % Trigger the update callback
                app.SelectFieldListBoxValueChanged();
            end
        end

        % Value changed function: FileDropDown
        function FileDropDownValueChanged(app, event)
            app.setLampBusy();         

            app.current_file = app.FileDropDown.Value;
            app.current_indx = 1;
            
            if app.status(app.current_file) == 0
                app.Warning.Visible = true;
                app.TabGroup.Visible = false;
                app.SelectFieldListBox.Items = {};
                app.SelectFieldListBox.Enable = false;
                app.FieldSelectedLabel.Text = "";
                app.Button_Next.Enable = false;
                app.Button_Previous.Enable = false;
                
                app.SelecttypeofEventsListBox.Items = {};
                app.SelecttypeofEventsListBox.Enable = false;
                app.StartingDateDatePicker.Enable = false;
                app.EndingDateDatePicker.Enable = false;
            else
                app.Warning.Visible = false;
                app.TabGroup.Visible = true;
                app.SelectFieldListBox.Enable = true;
                app.SelecttypeofEventsListBox.Enable = true;
                app.StartingDateDatePicker.Enable = true;
                app.EndingDateDatePicker.Enable = true;
                app.StartingDateDatePicker.Value = NaT;
                app.EndingDateDatePicker.Value = NaT;
                
                if any(contains(["chronic", "events_FFT", "events_histogram", "ETA"], app.tab))
                    app.getEventsNames;
                end
                
                if app.SelectFieldListBox.Visible
                    app.getFieldItems;
                    app.SelectFieldListBox.Value = 1;
                end
                app.SelectFieldListBoxValueChanged;

            end

            app.setLampReady();            
        end

        % Value changed function: SelecttypeofEventsListBox
        function SelecttypeofEventsListBoxValueChanged(app, event)
            app.setLampBusy();

            app.current_eventtype = app.SelecttypeofEventsListBox.Value;
            app.start_date = app.StartingDateDatePicker.Value;
            app.end_date = app.EndingDateDatePicker.Value;
            app.current_indx = 1;
            
            if isnat(app.start_date)
                app.start_date = datetime(1900, 1, 1);
            end
            
            if isnat(app.end_date)
                app.end_date = datetime('now', 'Format','d-MMM-y');
            end
            
            switch app.tab
                case "events_FFT"
                    app.getFieldItems;
                    app.SelectFieldListBox.Value = app.current_indx;

                    if ~isempty(app.items_names)
                        app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotMeanFFTProfile( ...
                            [app.UIAxes2_top, app.UIAxes2_bottom], app.current_eventtype, [app.start_date app.end_date]);
                        app.SelectFieldListBoxValueChanged;
                    else
                        cla
                    end

                case "ETA"
                    app.getFieldItems;
                    app.SelectFieldListBox.Value = app.current_indx;

                    if ~isempty(app.items_names)
                        app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        app.SelectFieldListBoxValueChanged;
                    else
                        cla
                    end
                case "events_histogram"
                    app.getFieldItems;
                    app.SelectFieldListBox.Value = app.current_indx;

                    if ~isempty(app.items_names)
                        app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                        app.SelectFieldListBoxValueChanged;
                    else
                        cla
                    end
                    
            end

            app.setLampReady();
        end

        % Value changed function: StartingDateDatePicker
        function StartingDateDatePickerValueChanged(app, event)
            app.start_date = app.StartingDateDatePicker.Value;
            app.getFieldItems;
            
            if ~isempty(app.items_names)
                app.FieldSelectedLabel.Text = app.items_names{app.current_indx};

                switch app.tab
                    case "events_FFT"
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotMeanFFTProfile( ...
                            [app.UIAxes2_top, app.UIAxes2_bottom], app.current_eventtype, [app.start_date app.end_date]);
                end
            end
            app.SelectFieldListBoxValueChanged;
        end

        % Value changed function: EndingDateDatePicker
        function EndingDateDatePickerValueChanged(app, event)
            app.end_date = app.EndingDateDatePicker.Value;
            app.getFieldItems;
            
            if ~isempty(app.items_names)
                app.FieldSelectedLabel.Text = app.items_names{app.current_indx};

                switch app.tab
                    case "events_FFT"
                        app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotMeanFFTProfile( ...
                            [app.UIAxes2_top, app.UIAxes2_bottom], app.current_eventtype, [app.start_date app.end_date]);
                end
            end
            app.SelectFieldListBoxValueChanged;
        end

        % Value changed function: SelectRecordingListBox
        function SelectRecordingListBoxValueChanged(app, event)
            app.setLampBusy();
            
            app.current_recording = app.SelectRecordingListBox.Value;

            axes = app.getAxesFromTab();
            if app.tab == "Stream CrossCorr" 
                app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotCrossCorrVar( axes, app.which_signal, str2double(app.current_recording) );

            elseif app.tab == "Stream Coherence"
                app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotCoherence_band( axes(1), app.which_signal, str2double(app.current_recording) );
                app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotCoherence( axes(2), app.which_signal, str2double(app.current_recording) );
                ylim( axes(1), get(axes(2), 'YLim' ) );
                linkaxes(axes, 'y');

            else

                % if ~contains(app.tab, "Stream")
                %     app.current_indx = 1;
                % end

                if app.tab == "Wearable"
                    app.getWearablesNames();
                end

                app.getFieldItems;
                if app.current_indx > numel(app.SelectFieldListBox.ItemsData)
                    app.current_indx = 1;
                end
                app.SelectFieldListBox.Value = app.current_indx;

                if ~isempty(app.items_names)
                    app.FieldSelectedLabel.Text = app.items_names{app.current_indx};
                    app.SelectFieldListBoxValueChanged;
                else
                    cla
                end

            end

            app.setLampReady();
        end

        % Button pushed function: CompareAllEventsButton
        function CompareAllEventsButtonPushed(app, event)
            app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotMeanFFTProfile();
        end

        % Value changed function: TimestampsSpinner
        function TimestampsSpinnerValueChanged(app, event)
            app.SelectFieldListBoxValueChanged;            
        end

        % Selection changed function: TypeSignalButtonGroup
        function TypeSignalButtonGroupSelectionChanged(app, event)
            app.which_signal = app.TypeSignalButtonGroup.SelectedObject.Text;
            app.SelectFieldListBoxValueChanged;            
        end

        % Value changed function: ContrastSwitch
        function ContrastSwitchValueChanged(app, event)
            app.setLampBusy();
            
            axes = app.getAxesFromTab();
            app.contrast = lower(app.ContrastSwitch.Value);
            streaming_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj;
            switch app.tab
                case "Stream summary"
                    streaming_obj.plotSpectrogram( axes(2), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal, 'Contrast', app.contrast );
                case "Stream Spectrogram"
                    streaming_obj.plotSpectrogram( axes(2), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal, 'Contrast', app.contrast );
                case "One Filter"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotFiltDataStream( -1, axes, str2double(app.current_recording), app.items_names{app.current_indx}, app.contrast );
                otherwise
            end
            
            app.setLampReady();
        end

        % Value changed function: SelectWearableListBox
        function SelectWearableListBoxValueChanged(app, event)
            app.setLampBusy();

            axes = app.getAxesFromTab();            
            app.current_wearable = app.SelectWearableListBox.Value;
            app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.wearables_obj.plotWearable( axes(3), app.current_wearable(1), app.current_wearable(2), 'Limits', app.recording_limits );
            app.alignAxes(axes);

            app.setLampReady();
        end

        % Value changed function: UTCDropDown
        function UTCDropDownValueChanged(app, event)
            app.setLampBusy();

            axes = app.getAxesFromTab();
            app.utc = str2double(app.UTCDropDown.Value);   

            app.getFieldItems;

            switch app.tab
                case "chronic"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotLFPTrendLogs( axes, app.utc );

                case "events_histogram"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotEventHistograms( ...
                        app.items_names{app.current_indx}, app.current_eventtype,  axes, app.utc );

                case "circadian"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.chronic_obj.plotCircadian( axes, app.utc );
                otherwise
            end

            app.setLampReady();
        end

        % Button pushed function: PlotBandpowerButton
        function PlotBandpowerButtonPushed(app, event)
            app.setLampBusy();
            
            switch app.FrequencyBandButtonGroup.SelectedObject.Text
               case "Beta (13-35 Hz)"
                    bounds = [13 35];
                case "Custom"
                    bounds = [str2double(app.LowerBound.Value) str2double(app.UpperBound.Value)];
           end

           data_type = app.TypeSignalButtonGroup.SelectedObject.Text;

            switch app.PowerButtonGroup.SelectedObject.Text
                case "Current Recording"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotBandpowerPerStimulationLevel(bounds, "RecordingIndices", str2double(app.current_recording), "DataType", data_type);
                case "All Recordings"
                    app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj.plotBandpowerPerStimulationLevel(bounds, "DataType", data_type);
            end

            app.setLampReady();
        end

        % Selection changed function: FrequencyBandButtonGroup
        function FrequencyBandButtonGroupSelectionChanged(app, event)
            if app.FrequencyBandButtonGroup.SelectedObject.Text == "Custom"
                app.LowerBound.Enable = true;
                app.UpperBound.Enable = true;
            else
                app.LowerBound.Enable = false;
                app.UpperBound.Enable = false;
            end
        end

        % Value changed function: Scalogram_LowerBound, 
        % ...and 1 other component
        function Scalogram_LimitsChanged(app, event)
            app.setLampBusy();

            lower_bound = app.Scalogram_LowerBound.Value;
            upper_bound = app.Scalogram_UpperBound.Value;

            if upper_bound < lower_bound
                upper_bound = lower_bound;
                lower_bound = app.Scalogram_UpperBound.Value;
                app.Scalogram_UpperBound.Value = upper_bound;
                app.Scalogram_LowerBound.Value = lower_bound;
            elseif upper_bound == lower_bound
                lower_bound = 0;
                app.Scalogram_LowerBound.Value = lower_bound;
            end
            
            axes = app.getAxesFromTab();
            
            streaming_obj = app.obj.ncnpercept_patient{app.current_patient}{app.current_file}.streaming_obj;
            switch app.tab
                case "Stream summary"
                    streaming_obj.plotScalogram( axes(3), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal, 'Limits', [lower_bound upper_bound] );
                case "Stream Wavelet"
                    streaming_obj.plotScalogram( axes(2), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal, 'Limits', [lower_bound upper_bound] );
                case "Wearable"
                    streaming_obj.plotScalogram( axes(2), str2double(app.current_recording), app.items_names{app.current_indx}, 'DataType', app.which_signal, 'Limits', [lower_bound upper_bound] );
                otherwise
            end

            app.setLampReady();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VisualizationWindowUIFigure = uifigure('Visible', 'off');
            app.VisualizationWindowUIFigure.Name = 'Visualization Window';
            app.VisualizationWindowLabel = uilabel(app.VisualizationWindowUIFigure);
            app.VisualizationWindowLabel.Text = 'Visualization Window';
            app.Panel = uipanel(app.VisualizationWindowUIFigure);
            app.SelectRecordingListBox = uilistbox(app.Panel);
            app.SelectRecordingListBox.ValueChangedFcn = createCallbackFcn(app, @SelectRecordingListBoxValueChanged, true);
            app.SelectRecordingListBox.Visible = 'off';
            app.SelecttypeofEventsListBox = uilistbox(app.Panel);
            app.SelecttypeofEventsListBox.ValueChangedFcn = createCallbackFcn(app, @SelecttypeofEventsListBoxValueChanged, true);
            app.SelecttypeofEventsListBox.Visible = 'off';
            app.SelectWearableListBox = uilistbox(app.Panel);
            app.SelectWearableListBox.ValueChangedFcn = createCallbackFcn(app, @SelectWearableListBoxValueChanged, true);
            app.SelectWearableListBox.Visible = 'off';
            app.TabGroup = uitabgroup(app.Panel);
            app.Tab_1 = uitab(app.TabGroup);
            app.Tab_1.Title = '(1,1,1)';
            app.UIAxes1 = uiaxes(app.Tab_1);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '(2,1,2)';
            app.UIAxes2_top = uiaxes(app.Tab_2);
            title(app.UIAxes2_top, 'Title')
            xlabel(app.UIAxes2_top, 'X')
            ylabel(app.UIAxes2_top, 'Y')
            zlabel(app.UIAxes2_top, 'Z')
            app.UIAxes2_bottom = uiaxes(app.Tab_2);
            title(app.UIAxes2_bottom, 'Title')
            xlabel(app.UIAxes2_bottom, 'X')
            ylabel(app.UIAxes2_bottom, 'Y')
            zlabel(app.UIAxes2_bottom, 'Z')
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '(2,2,3)';
            app.UIAxes3_bottom = uiaxes(app.Tab_3);
            title(app.UIAxes3_bottom, 'Title')
            xlabel(app.UIAxes3_bottom, 'X')
            ylabel(app.UIAxes3_bottom, 'Y')
            zlabel(app.UIAxes3_bottom, 'Z')
            app.UIAxes3_topleft = uiaxes(app.Tab_3);
            title(app.UIAxes3_topleft, 'Title')
            xlabel(app.UIAxes3_topleft, 'X')
            ylabel(app.UIAxes3_topleft, 'Y')
            zlabel(app.UIAxes3_topleft, 'Z')
            app.UIAxes3_topright = uiaxes(app.Tab_3);
            title(app.UIAxes3_topright, 'Title')
            xlabel(app.UIAxes3_topright, 'X')
            ylabel(app.UIAxes3_topright, 'Y')
            zlabel(app.UIAxes3_topright, 'Z')
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '(2,2,4)';
            app.UIAxes4_topleft = uiaxes(app.Tab_4);
            title(app.UIAxes4_topleft, 'Title')
            xlabel(app.UIAxes4_topleft, 'X')
            ylabel(app.UIAxes4_topleft, 'Y')
            zlabel(app.UIAxes4_topleft, 'Z')
            app.UIAxes4_bottomleft = uiaxes(app.Tab_4);
            title(app.UIAxes4_bottomleft, 'Title')
            xlabel(app.UIAxes4_bottomleft, 'X')
            ylabel(app.UIAxes4_bottomleft, 'Y')
            zlabel(app.UIAxes4_bottomleft, 'Z')
            app.UIAxes4_bottomright = uiaxes(app.Tab_4);
            title(app.UIAxes4_bottomright, 'Title')
            xlabel(app.UIAxes4_bottomright, 'X')
            ylabel(app.UIAxes4_bottomright, 'Y')
            zlabel(app.UIAxes4_bottomright, 'Z')
            app.UIAxes4_topright = uiaxes(app.Tab_4);
            title(app.UIAxes4_topright, 'Title')
            xlabel(app.UIAxes4_topright, 'X')
            ylabel(app.UIAxes4_topright, 'Y')
            zlabel(app.UIAxes4_topright, 'Z')
            app.Panel_topleft = uipanel(app.Tab_4);
            app.Panel_topleft.Visible = 'off';
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '(3,1,3)';
            app.UIAxes5_top = uiaxes(app.Tab_5);
            title(app.UIAxes5_top, 'Title')
            xlabel(app.UIAxes5_top, 'X')
            ylabel(app.UIAxes5_top, 'Y')
            zlabel(app.UIAxes5_top, 'Z')
            app.UIAxes5_middle = uiaxes(app.Tab_5);
            title(app.UIAxes5_middle, 'Title')
            xlabel(app.UIAxes5_middle, 'X')
            ylabel(app.UIAxes5_middle, 'Y')
            zlabel(app.UIAxes5_middle, 'Z')
            app.UIAxes5_bottom = uiaxes(app.Tab_5);
            title(app.UIAxes5_bottom, 'Title')
            xlabel(app.UIAxes5_bottom, 'X')
            ylabel(app.UIAxes5_bottom, 'Y')
            zlabel(app.UIAxes5_bottom, 'Z')
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '(1,2,2)';
            app.UIAxes6_right = uiaxes(app.Tab_6);
            title(app.UIAxes6_right, 'Title')
            xlabel(app.UIAxes6_right, 'X')
            ylabel(app.UIAxes6_right, 'Y')
            zlabel(app.UIAxes6_right, 'Z')
            app.UIAxes6_left = uiaxes(app.Tab_6);
            title(app.UIAxes6_left, 'Title')
            xlabel(app.UIAxes6_left, 'X')
            ylabel(app.UIAxes6_left, 'Y')
            zlabel(app.UIAxes6_left, 'Z')
            app.SelectFieldListBox = uilistbox(app.Panel);
            app.SelectFieldListBox.Multiselect = 'on';
            app.SelectFieldListBox.ValueChangedFcn = createCallbackFcn(app, @SelectFieldListBoxValueChanged, true);
            app.SelectFieldListBox.Value = {'Item 1'};
            app.SelectFieldLabel = uilabel(app.Panel);
            app.SelectFieldLabel.Text = 'Select Field:';
            app.FieldSelectedLabel = uilabel(app.Panel);
            app.FieldSelectedLabel.Text = '  Field Selected';
            app.Button_Previous = uibutton(app.Panel, 'push');
            app.Button_Previous.ButtonPushedFcn = createCallbackFcn(app, @Button_PreviousPushed, true);
            app.Button_Previous.Text = '<<';
            app.Button_Next = uibutton(app.Panel, 'push');
            app.Button_Next.ButtonPushedFcn = createCallbackFcn(app, @Button_NextPushed, true);
            app.Button_Next.Text = '>>';
            app.Warning = uilabel(app.Panel);
            app.Warning.Visible = 'off';
            app.Warning.Text = 'This file does not contain this type of data.';
            app.SelectRecordingListBoxLabel = uilabel(app.Panel);
            app.SelectRecordingListBoxLabel.Visible = 'off';
            app.SelectRecordingListBoxLabel.Text = 'Select Recording:';
            app.FileDropDown = uidropdown(app.Panel);
            app.FileDropDown.ValueChangedFcn = createCallbackFcn(app, @FileDropDownValueChanged, true);
            app.SelecttypeofEventsListBoxLabel = uilabel(app.Panel);
            app.SelecttypeofEventsListBoxLabel.Visible = 'off';
            app.SelecttypeofEventsListBoxLabel.Text = 'Select type of Events:';
            app.SelectWearableListBoxLabel = uilabel(app.Panel);
            app.SelectWearableListBoxLabel.Visible = 'off';
            app.SelectWearableListBoxLabel.Text = 'Select Wearable:';
            app.FrequencyBandButtonGroup = uibuttongroup(app.Panel);
            app.FrequencyBandButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FrequencyBandButtonGroupSelectionChanged, true);
            app.FrequencyBandButtonGroup.Title = 'Frequency Band';
            app.FrequencyBandButtonGroup.Visible = 'off';
            app.BetaButton = uiradiobutton(app.FrequencyBandButtonGroup);
            app.BetaButton.Text = 'Beta (13-35 Hz)';
            app.BetaButton.Value = true;
            app.CustomButton = uiradiobutton(app.FrequencyBandButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.LowerBound = uieditfield(app.FrequencyBandButtonGroup, 'text');
            app.LowerBound.CharacterLimits = [0 124];
            app.LowerBound.InputType = 'digits';
            app.LowerBound.Enable = 'off';
            app.DashBounds = uilabel(app.FrequencyBandButtonGroup);
            app.DashBounds.Text = '-         Hz';
            app.UpperBound = uieditfield(app.FrequencyBandButtonGroup, 'text');
            app.UpperBound.CharacterLimits = [0 125];
            app.UpperBound.InputType = 'digits';
            app.UpperBound.Enable = 'off';
            app.VisualizationLabel = uilabel(app.Panel);
            app.VisualizationLabel.Text = 'Visualization';
            app.AnalysisLabel = uilabel(app.Panel);
            app.AnalysisLabel.Visible = 'off';
            app.AnalysisLabel.Text = 'Analysis';
            app.PowerButtonGroup = uibuttongroup(app.Panel);
            app.PowerButtonGroup.Visible = 'off';
            app.CurrentRecordingButton = uiradiobutton(app.PowerButtonGroup);
            app.CurrentRecordingButton.Text = 'Current Recording';
            app.CurrentRecordingButton.Value = true;
            app.AllRecordingsButton = uiradiobutton(app.PowerButtonGroup);
            app.AllRecordingsButton.Text = 'All Recordings';
            app.PlotBandpowerButton = uibutton(app.Panel, 'push');
            app.PlotBandpowerButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBandpowerButtonPushed, true);
            app.PlotBandpowerButton.Visible = 'off';
            app.PlotBandpowerButton.Text = 'Plot Bandpower';
            app.Interactivity_TabGroup = uitabgroup(app.Panel);
            app.Empty_Tab = uitab(app.Interactivity_TabGroup);
            app.Empty_Tab.Title = 'Empty';
            app.ETA_Tab = uitab(app.Interactivity_TabGroup);
            app.ETA_Tab.Title = 'Tab';
            app.NumberoftimestampsLabel = uilabel(app.ETA_Tab);
            app.NumberoftimestampsLabel.Text = 'Number of timestamps(±):';
            app.TimestampsSpinner = uispinner(app.ETA_Tab);
            app.TimestampsSpinner.Limits = [1 Inf];
            app.TimestampsSpinner.ValueChangedFcn = createCallbackFcn(app, @TimestampsSpinnerValueChanged, true);
            app.TimestampsSpinner.Value = 5;
            app.EVNT_Tab = uitab(app.Interactivity_TabGroup);
            app.EVNT_Tab.Title = 'Tab2';
            app.StartingDateDatePickerLabel = uilabel(app.EVNT_Tab);
            app.StartingDateDatePickerLabel.Text = 'Starting Date';
            app.StartingDateDatePicker = uidatepicker(app.EVNT_Tab);
            app.StartingDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartingDateDatePickerValueChanged, true);
            app.EndingDateDatePickerLabel = uilabel(app.EVNT_Tab);
            app.EndingDateDatePickerLabel.Text = 'Ending Date';
            app.EndingDateDatePicker = uidatepicker(app.EVNT_Tab);
            app.EndingDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EndingDateDatePickerValueChanged, true);
            app.TML_Tab = uitab(app.Interactivity_TabGroup);
            app.TML_Tab.Title = 'Tab3';
            app.UTCDropDownLabel = uilabel(app.TML_Tab);
            app.UTCDropDownLabel.Text = 'UTC:';
            app.UTCDropDown = uidropdown(app.TML_Tab);
            app.UTCDropDown.Items = {'-12', '-11', '-10', '-9', '-8', '-7', '-6', '-5', '-4', '-3', '-2', '-1', '+0', '+1', '+2', '+3', '+4', '+5', '+6', '+7', '+8', '+9', '+10', '+11', '+12', '+13', '+14'};
            app.UTCDropDown.ValueChangedFcn = createCallbackFcn(app, @UTCDropDownValueChanged, true);
            app.UTCDropDown.Value = '+0';
            app.SUM_Tab = uitab(app.Interactivity_TabGroup);
            app.SUM_Tab.Title = 'Tab6';
            app.ContrastSwitch = uiswitch(app.SUM_Tab, 'slider');
            app.ContrastSwitch.Items = {'Normal', 'High'};
            app.ContrastSwitch.ValueChangedFcn = createCallbackFcn(app, @ContrastSwitchValueChanged, true);
            app.ContrastSwitch.Value = 'Normal';
            app.Spectrogram_ContrastLabel = uilabel(app.SUM_Tab);
            app.Spectrogram_ContrastLabel.Text = 'Spectrogram Contrast:';
            app.Scalogram_DashBounds = uilabel(app.SUM_Tab);
            app.Scalogram_DashBounds.Text = '-              Hz';
            app.Scalogram_LimitsLabel = uilabel(app.SUM_Tab);
            app.Scalogram_LimitsLabel.Text = 'Scalogram Limits:';
            app.Scalogram_LowerBound = uieditfield(app.SUM_Tab, 'numeric');
            app.Scalogram_LowerBound.Limits = [0 92];
            app.Scalogram_LowerBound.ValueChangedFcn = createCallbackFcn(app, @Scalogram_LimitsChanged, true);
            app.Scalogram_LowerBound.Value = 1;
            app.Scalogram_UpperBound = uieditfield(app.SUM_Tab, 'numeric');
            app.Scalogram_UpperBound.Limits = [4 92];
            app.Scalogram_UpperBound.ValueChangedFcn = createCallbackFcn(app, @Scalogram_LimitsChanged, true);
            app.Scalogram_UpperBound.Value = 92;
            app.Tab7 = uitab(app.Interactivity_TabGroup);
            app.Tab7.Title = 'Tab7';
            app.Empty_Panel_top = uipanel(app.Panel);
            app.Empty_Panel_left = uipanel(app.Panel);
            app.Empty_Panel_bottom = uipanel(app.Panel);
            app.Empty_Panel_right = uipanel(app.Panel);
            app.TypeSignalButtonGroup = uibuttongroup(app.Panel);
            app.TypeSignalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeSignalButtonGroupSelectionChanged, true);
            app.TypeSignalButtonGroup.Title = 'Type of Signal';
            app.TypeSignalButtonGroup.Visible = 'off';
            app.RawButton = uiradiobutton(app.TypeSignalButtonGroup);
            app.RawButton.Text = 'Raw';
            app.RawButton.Value = true;
            app.LatestFilteredButton = uiradiobutton(app.TypeSignalButtonGroup);
            app.LatestFilteredButton.Text = 'Latest Filtered';
            app.ECGCleanedButton = uiradiobutton(app.TypeSignalButtonGroup);
            app.ECGCleanedButton.Text = 'ECG Cleaned';
            app.CompareAllEventsButton = uibutton(app.Panel, 'push');
            app.CompareAllEventsButton.ButtonPushedFcn = createCallbackFcn(app, @CompareAllEventsButtonPushed, true);
            app.CompareAllEventsButton.Visible = 'off';
            app.CompareAllEventsButton.Text = 'Compare all events';
            app.ReadyLamp = uilamp(app.VisualizationWindowUIFigure);
            app.ReadyLamp_Label = uilabel(app.VisualizationWindowUIFigure);
            app.ReadyLamp_Label.Text = 'Ready';
            app.Label = uilabel(app.VisualizationWindowUIFigure);
            app.Label.Text = 'Neuroengineering and Computational Neuroscience, i3S 2023  |';
            app.Hyperlink = uihyperlink(app.VisualizationWindowUIFigure);
            app.Hyperlink.URL = 'https://github.com/NCN-Lab/DBScope';
            app.Hyperlink.Text = 'https://github.com/NCN-Lab/DBScope';
            % Show the figure after all components are created
            app.VisualizationWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualizationWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisualizationWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisualizationWindowUIFigure)
        end
    end
end