classdef DBScope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DBScopeUIFigure                matlab.ui.Figure
        DBScopeLabel                   matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        Hyperlink                      matlab.ui.control.Hyperlink
        ClearText                      matlab.ui.control.Button
        PatientIDDropDown              matlab.ui.control.DropDown
        PatientLabel                   matlab.ui.control.Label
        AnonymizeDataButton            matlab.ui.control.Button
        Label_2                        matlab.ui.control.Label
        Streaming_OnlineStreamingSensing_Panel_2  matlab.ui.container.Panel
        Streaming_WerablesPanel        matlab.ui.container.Panel
        LoadWearablesButton            matlab.ui.control.Button
        Wearables_CrossAnalysisButton  matlab.ui.control.Button
        Streaming_CleanECG             matlab.ui.control.Button
        StreamingSummary_Button        matlab.ui.control.Button
        Streaming_SpectralAnalysisPanel  matlab.ui.container.Panel
        Streaming_SpectralAnalysis_DropDown  matlab.ui.control.DropDown
        Streaming_ApplyButton          matlab.ui.control.Button
        Streaming_FilterData_Button    matlab.ui.control.Button
        Chronic_SensingPanel           matlab.ui.container.Panel
        Chronic_Events_DropDown        matlab.ui.control.DropDown
        Chronic_Events_Button          matlab.ui.control.Button
        Chronic_Visualizedata_Button   matlab.ui.control.Button
        Chronic_CoherencePanel         matlab.ui.container.Panel
        Chronic_ApplyButton            matlab.ui.control.Button
        Chronic_DropDown               matlab.ui.control.DropDown
        EventsPanel                    matlab.ui.container.Panel
        ReadyLamp_Label                matlab.ui.control.Label
        ReadyLamp                      matlab.ui.control.Lamp
        SaveWorkspace_Button           matlab.ui.control.Button
        Display_TextArea               matlab.ui.control.TextArea
        Closeall_Button                matlab.ui.control.Button
        SystemInformationPanel         matlab.ui.container.Panel
        SystemInformation_ApplyButton  matlab.ui.control.Button
        SystemInformation_DropDown     matlab.ui.control.DropDown
        Calibration_Panel              matlab.ui.container.Panel
        Calibration_ApplyButton        matlab.ui.control.Button
        Calibration_DropDown           matlab.ui.control.DropDown
        Calibration_InspectforArtifacts_Button  matlab.ui.control.Button
        LoadFileButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        obj % obj from class NCNPERCEPT_BATCH
        current_patient 
        filter_indx % empty, to be used in the filtering section
    end
    
    methods (Access = private)
        
        function enableSections(app)

            % Enable streaming parameters
            streaming = [];
            for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                streaming = [streaming; app.obj.ncnpercept_patient{app.current_patient}{file_indx}.status.streaming];
            end
            if any(streaming)
                app.Streaming_CleanECG.Enable = true;
            else
                app.Streaming_CleanECG.Enable = false;
            end

            for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                if ~any( ~isfield( app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.streaming_parameters, "ecg_data" ) )
                    app.Streaming_FilterData_Button.Enable = true;
                    app.StreamingSummary_Button.Enable = true;
                    app.Streaming_SpectralAnalysis_DropDown.Enable = true;
                    app.LoadWearablesButton.Enable = true;

                    if ~any( isempty( app.obj.ncnpercept_patient{app.current_patient}{file_indx}.wearables_obj.data ) )
                        app.Wearables_CrossAnalysisButton.Enable = true;
                    end
                end
            end

            % Enable chronic parameters
            chronic = [];
            for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                chronic = [ chronic; app.obj.ncnpercept_patient{app.current_patient}{file_indx}.status.chronic];
            end
            if any(chronic)
                app.Chronic_Visualizedata_Button.Enable = true;
                app.Chronic_DropDown.Enable = true;
            else
                app.Chronic_Visualizedata_Button.Enable = false;
                app.Chronic_DropDown.Enable = false;
            end

            % Enable events parameters
            events = [];
            for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                events = [ events; app.obj.ncnpercept_patient{app.current_patient}{file_indx}.status.events];
            end
            if any(events)
                app.Chronic_Events_DropDown.Enable = true;
            else
                app.Chronic_Events_DropDown.Enable = false;
                disp("There are no events recorded.")
            end
            
            % See if auto and cross-correlations are justified.
            app.Chronic_DropDown.Items = {'Select Option', 'Circadian Profile'};
            for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                temp = app.obj.ncnpercept_patient{app.current_patient}{file_indx}.chronic_obj.chronic_parameters.time_domain;
                if length(temp.time) >= 6*24*2 % More than 2 days
                    app.Chronic_DropDown.Items = {'Select Option', 'Circadian Profile', 'Auto-correlation', 'Cross-correlation'};
                    break;
                end
            end

            % Inspect for artifact if survey recordings are available
            for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                if app.obj.ncnpercept_patient{app.current_patient}{file_indx}.status.survey == 1
                    app.Calibration_InspectforArtifacts_Button.Enable = true;
                end
            end

            % Calibration DropDown Items
            Items_dropDown = [];
            Items_dropDown{1} = 'Select Option';
            Items_dropDown{2} = 'Impedance';
            setup_on_item = 0;
            setup_off_item = 0;
            survey_item = 0;
            indefinite_item = 0;
            for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                if app.obj.ncnpercept_patient{app.current_patient}{file_indx}.status.setup_on == 1
                    setup_on_item = 1;
                end
                if app.obj.ncnpercept_patient{app.current_patient}{file_indx}.status.setup_off == 1
                    setup_off_item = 1;
                end
                if app.obj.ncnpercept_patient{app.current_patient}{file_indx}.status.survey == 1
                    survey_item = 1;
                end
                if app.obj.ncnpercept_patient{app.current_patient}{file_indx}.status.indefinite == 1
                    indefinite_item = 1;
                end

            end
            if setup_on_item == 1
                Items_dropDown{end+1} = 'Setup ON';
            end
            if setup_off_item == 1
                Items_dropDown{end+1} = 'Setup OFF';
            end
            if survey_item == 1
                Items_dropDown{end+1} = 'Artifact Status';
                Items_dropDown{end+1} = 'Survey';
            end
            if indefinite_item ==1
                Items_dropDown{end+1} = 'Indefinite Streaming';
            end

            app.Calibration_DropDown.Items = Items_dropDown;


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          app.obj = NCNPERCEPT_BATCH;
          app.TabGroup.Visible = true;
          app.current_patient = 1;
          app.filter_indx= 1;
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            app.current_patient = 1;

            app.Streaming_FilterData_Button.Enable = false;
            app.StreamingSummary_Button.Enable = false;
            app.Streaming_SpectralAnalysis_DropDown.Enable = false;
            app.Streaming_SpectralAnalysis_DropDown.Value = "Select Option";
            app.Streaming_ApplyButton.Enable = false;
            app.LoadWearablesButton.Enable = false;
            app.Wearables_CrossAnalysisButton.Enable = false;

            [ loading_mode ] = app.obj.aux_open_batch();

            if isempty(loading_mode)
                return;
            end
            switch loading_mode
                case 'folder'
                    [ status, text ] = app.obj.open_batch_folder();
                case 'files'
                    [ status, text ] = app.obj.open_batch_files();
                case 'workspace'
                    [ status, text, app.obj ] = app.obj.open_mat_files();
            end

            if status == 1

                app.PatientIDDropDown.Items = app.obj.patient_list;
                app.PatientIDDropDown.ItemsData = 1:numel(app.obj.patient_list);
                app.PatientIDDropDown.Value = app.current_patient;
                app.PatientIDDropDown.Enable = true;
                
                app.SystemInformation_DropDown.Enable = true;
                app.Calibration_DropDown.Enable = true;

                app.enableSections();

                app.Display_TextArea.Value = ('# File(s) loaded');
                app.Display_TextArea.Value = [ app.Display_TextArea.Value; text; '       ' ];
%                 app.SaveWorkspace_Button_single.Enable = true;

            end
        end

        % Button pushed function: Closeall_Button
        function Closeall_ButtonPushed(app, event)
            all_fig = findall(0, 'type', 'figure');            
            delete(all_fig(~strcmp({all_fig.Name}, 'DBScope'))); 
        end

        % Value changed function: SystemInformation_DropDown
        function SystemInformation_DropDownValueChanged(app, event)
            if app.SystemInformation_DropDown.Value == "Select Option"
                app.SystemInformation_ApplyButton.Enable = false;
            else
                app.SystemInformation_ApplyButton.Enable = true;
            end
        end

        % Button pushed function: SystemInformation_ApplyButton
        function SystemInformation_ApplyButtonPushed(app, event)
            app.ReadyLamp_Label.Text = 'Busy';
            app.ReadyLamp.Color = [0.64,0.08,0.18];
            drawnow;
            app.SystemInformation_ApplyButton.Enable = false;
            app.Calibration_ApplyButton.Enable = false;
            app.LoadFileButton.Enable = false;

            % Access system information parameters
            switch app.SystemInformation_DropDown.Value
                case 'Session Information'
                    for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        text = app.obj.ncnpercept_patient{app.current_patient}{file_indx}.getSessionInformation();
                        app.Display_TextArea.Value = [ app.Display_TextArea.Value;
                            '# Session Information: ';
                            '       ' ;
                            text;
                            '       ';
                            "=======================";
                            '       ' ];

                    end
                case 'Patient Information'
                    for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        text = app.obj.ncnpercept_patient{app.current_patient}{file_indx}.getPatientInformation();
                        app.Display_TextArea.Value = [ app.Display_TextArea.Value;
                            '# Patient Information: ';
                             '       ' ;
                            text;
                            '       ';
                            "=======================";
                            '       ' ];
                    end
                case 'Device Information'
                    for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        text = app.obj.ncnpercept_patient{app.current_patient}{file_indx}.getDeviceInformation( );
                        app.Display_TextArea.Value = [ app.Display_TextArea.Value;
                            '# Device Information: '
                             '       ' ;
                            text;
                            '       ';
                            "=======================";
                            '       ' ];
                    end
                case 'Battery Information'
                    for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        text = app.obj.ncnpercept_patient{app.current_patient}{file_indx}.getBatteryInformation();
                        app.Display_TextArea.Value = [ app.Display_TextArea.Value;
                            '# Battery Information: '
                             '       ' ;
                            text;
                            '       ';
                            "=======================";
                            '       ' ];
                    end
                case 'Lead Configuration'
                    for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        text = app.obj.ncnpercept_patient{app.current_patient}{file_indx}.getLeadConfig();
                        app.Display_TextArea.Value = [ app.Display_TextArea.Value;
                            '# Lead Configuration: '
                             '       ' ;
                            text;
                            '       ';
                            "=======================";
                            '       ' ];
                    end
                case 'Stimulation Status'
                    for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        text = app.obj.ncnpercept_patient{app.current_patient}{file_indx}.getStimStatus();
                        app.Display_TextArea.Value = [ app.Display_TextArea.Value;
                            '# Stimulation Status: '
                             '       ' ;
                            text;
                            '       ';
                            "=======================";
                            '       ' ];
                    end
                case 'Groups Information'
                    for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        text = app.obj.ncnpercept_patient{app.current_patient}{file_indx}.getGroupsInformation();
                        app.Display_TextArea.Value = [ app.Display_TextArea.Value;
                            '# Groups Information: '
                             '       ' ;
                            text;
                            '       ';
                            "=======================";
                            '       ' ];
                    end
                otherwise
            end

            app.ReadyLamp_Label.Text = 'Ready';
            app.ReadyLamp.Color = [0.47,0.67,0.19];
            drawnow;
            app.SystemInformation_ApplyButton.Enable = true;
            app.Calibration_ApplyButton.Enable = true;
            app.LoadFileButton.Enable = true;
        end

        % Value changed function: Display_TextArea
        function Display_TextAreaValueChanged(app, event)
            value = app.Display_TextArea_batch_batch.Value;
        end

        % Value changed function: Calibration_DropDown
        function Calibration_DropDownValueChanged(app, event)
            if app.Calibration_DropDown.Value == "Select Option"
                app.Calibration_ApplyButton.Enable = false;
            else
                app.Calibration_ApplyButton.Enable = true;
            end
        end

        % Button pushed function: Calibration_ApplyButton
        function Calibration_ApplyButtonPushed(app, event)
            app.ReadyLamp_Label.Text = 'Busy';
            app.ReadyLamp.Color = [0.64,0.08,0.18];
            drawnow;
            app.SystemInformation_ApplyButton.Enable = false;
            app.Calibration_ApplyButton.Enable = false;
            app.LoadFileButton.Enable = false;

            app.Calibration_ApplyButton.Enable = false;

            % Access calibration parameters
            switch app.Calibration_DropDown.Value
                case 'Impedance'
                    VisualizationWindow( app.obj, app.current_patient, "impedance", 0 );
                    app.Calibration_ApplyButton.Enable = true;
                    app.Display_TextArea.Value = [ app.Display_TextArea.Value; "# Running: plot Impedance."; '       ' ];
                case 'Artifact Status'
                    for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                    value = [];
                    for i= 1:length(app.obj.ncnpercept_patient{app.current_patient}{file_indx}.parameters.mostrecent.artifactstatus)
                        if ~isempty(app.obj.ncnpercept_patient{app.current_patient}{file_indx}.parameters.mostrecent.artifactstatus{i})
                        value = [value,newline...
                            app.obj.ncnpercept_patient{app.current_patient}{file_indx}.parameters.mostrecent.artifactstatus{i}];
                        end
                    end
                    app.Display_TextArea.Value = [ app.Display_TextArea.Value; value, newline ];
                    end
                case 'Survey'
                    VisualizationWindow( app.obj, app.current_patient,  "survey", 0 );
                    app.Calibration_ApplyButton.Enable = true;
                    app.Display_TextArea.Value = [ app.Display_TextArea.Value; "# Running: plot Survey."; '       ' ];
                case 'Indefinite Streaming'
                    VisualizationWindow( app.obj, app.current_patient,  "indefinite", 0 );
                    app.Calibration_ApplyButton.Enable = true;
                    app.Display_TextArea.Value = [ app.Display_TextArea.Value; "# Running: plot Indefinite Streaming."; '       ' ];
                case 'Setup OFF'
                    VisualizationWindow( app.obj, app.current_patient,  "setup_off", 0 );
                    app.Calibration_ApplyButton.Enable = true;
                    app.Display_TextArea.Value = [ app.Display_TextArea.Value; "# Running: plot Setup Off."; '       ' ];
                case 'Setup ON'
                    VisualizationWindow( app.obj, app.current_patient,  "setup_on", 0 );
                    app.Calibration_ApplyButton.Enable = true;
                    app.Display_TextArea.Value = [ app.Display_TextArea.Value; "# Running: plot Setup On."; '       ' ];
                otherwise
            end
            app.Calibration_ApplyButton.Enable = true;

            app.ReadyLamp_Label.Text = 'Ready';
            app.ReadyLamp.Color = [0.47,0.67,0.19];
            drawnow;
            app.SystemInformation_ApplyButton.Enable = true;
            app.Calibration_ApplyButton.Enable = true;
            app.LoadFileButton.Enable = true;
        end

        % Button pushed function: Calibration_InspectforArtifacts_Button
        function Calibration_InspectforArtifacts_ButtonPushed(app, event)
             % Open secondary GUI for Artifact inspection
            ScanArtifacts( app.obj)
            app.Display_TextArea.Value = [ app.Display_TextArea.Value; "# Opening: Scan Artifacts tool."; '       ' ];
        end

        % Button pushed function: SaveWorkspace_Button
        function SaveWorkspace_ButtonPushed(app, event)
            % Save workspace - MATLAB or FIELDTRIP format
            answer = questdlg('In which format do you want to export the workspace?', ...
                '', 'DBScope','FIELDTRIP', 'FIELDTRIP');
            switch answer
                case 'DBScope'
                    for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                        app.obj.exportWorkspace();
                    end
                case 'FIELDTRIP'
                    for patient_indx = 1:numel(app.obj.ncnpercept_patient)
                        for file_indx = 1:numel(app.obj.ncnpercept_patient{patient_indx})
                            app.obj.ncnpercept_patient{patient_indx}{file_indx}.exportFieldtrip();
                        end
                    end
            end
        end

        % Button down function: Tab
        function TabButtonDown(app, event)
            
        end

        % Button pushed function: Chronic_Visualizedata_Button
        function Chronic_Visualizedata_ButtonPushed(app, event)
           app.ReadyLamp_Label.Text = 'Busy';
            app.ReadyLamp.Color = [0.64,0.08,0.18];
            drawnow;
            app.SystemInformation_ApplyButton.Enable = false;
            app.Calibration_ApplyButton.Enable = false;
            app.LoadFileButton.Enable = false;
            
            % Visualize chronic data
            VisualizationWindow( app.obj, app.current_patient,  "chronic", 0 );
            app.Display_TextArea.Value = [ app.Display_TextArea.Value; "# Running: plot Timeline."; '       ' ];
            
            % Enable chronic parameters and disable streaming parameters
            app.Streaming_ApplyButton.Enable = false;
            app.StreamingSummary_Button.Enable = false;
            app.Chronic_ApplyButton.Enable = true;
            
            app.ReadyLamp_Label.Text = 'Ready';
            app.ReadyLamp.Color = [0.47,0.67,0.19];
            drawnow;
            app.SystemInformation_ApplyButton.Enable = true;
            app.Calibration_ApplyButton.Enable = true;
            app.LoadFileButton.Enable = true;
        end

        % Button pushed function: Streaming_CleanECG
        function Streaming_CleanECGButtonPushed(app, event)
            app.ReadyLamp_Label.Text = 'Busy';
            app.ReadyLamp.Color = [0.64,0.08,0.18];
            drawnow;
            app.SystemInformation_ApplyButton.Enable = false;
            app.Calibration_ApplyButton.Enable = false;
            app.LoadFileButton.Enable = false;
            app.LoadWearablesButton.Enable = false;

            % Clear ECG artifacts present in streaming data
            for file_indx = 1:length(app.obj.ncnpercept_patient{app.current_patient})
                text = app.obj.ncnpercept_patient{app.current_patient}{file_indx}.streaming_obj.cleanECG(250);
                dt = app.obj.ncnpercept_patient{app.current_patient}{file_indx}.parameters.session_date;
                dt.Format = 'yyyy-MMM-dd';
                f = msgbox(string(dt) + " Session" + newline + text, "ECG Scan Results");
            end
            disp('ECG artifact cleaning complete')
            text = ["# ECG artifact cleaning complete."];
            app.Display_TextArea.Value = [ app.Display_TextArea.Value; text; '       '];

            % Enable streaming parameters and disable chronic parameters
            app.Streaming_SpectralAnalysis_DropDown.Enable = true;
            app.Streaming_FilterData_Button.Enable = true;
            app.StreamingSummary_Button.Enable = true;

            app.ReadyLamp_Label.Text = 'Ready';
            app.ReadyLamp.Color = [0.47,0.67,0.19];
            drawnow;
            app.SystemInformation_ApplyButton.Enable = true;
            app.Calibration_ApplyButton.Enable = true;
            app.LoadFileButton.Enable = true;
            app.LoadWearablesButton.Enable = true;
        end

        % Button pushed function: Streaming_FilterData_Button
        function Streaming_FilterData_ButtonPushed(app, event)
            app.SystemInformation_ApplyButton.Enable = false;
            app.Calibration_ApplyButton.Enable = false;
            app.LoadFileButton.Enable = false;
            app.ReadyLamp_Label.Text = 'Busy';
            app.ReadyLamp.Color = [0.64,0.08,0.18];
            drawnow;

            % Secondary GUI - filter data
            FilterData(  app.obj )
            app.Display_TextArea.Value = [ app.Display_TextArea.Value; "# Openning: Filter tool."; '       ' ];

            % Enable streaming parameters and disable chronic parameters
            app.Streaming_ApplyButton.Enable = true;
            app.StreamingSummary_Button.Enable = true;
            app.Chronic_ApplyButton.Enable = false;

            app.SystemInformation_ApplyButton.Enable = true;
            app.Calibration_ApplyButton.Enable = true;
            app.LoadFileButton.Enable = true;
            app.StreamingSummary_Button.Enable = true;
            app.ReadyLamp_Label.Text = 'Ready';
            app.ReadyLamp.Color = [0.47,0.67,0.19];
            drawnow;
        end

        % Button pushed function: StreamingSummary_Button
        function StreamingSummary_ButtonPushed(app, event)
            app.SystemInformation_ApplyButton.Enable = false;
            app.Calibration_ApplyButton.Enable = false;
            app.LoadFileButton.Enable = false;
            app.ReadyLamp_Label.Text = 'Busy';
            app.ReadyLamp.Color = [0.64,0.08,0.18];
            drawnow;

            % Plot summary visualizations of streaming data
            VisualizationWindow( app.obj, app.current_patient,  "Stream summary", 0 );
            app.Display_TextArea.Value = [ app.Display_TextArea.Value; "# Running: Streaming Summary"; '       ' ];

            app.SystemInformation_ApplyButton.Enable = true;
            app.Calibration_ApplyButton.Enable = true;
            app.LoadFileButton.Enable = true;
            app.ReadyLamp_Label.Text = 'Ready';
            app.ReadyLamp.Color = [0.47,0.67,0.19];
            drawnow;
        end

        % Value changed function: Streaming_SpectralAnalysis_DropDown
        function Streaming_SpectralAnalysis_DropDownValueChanged(app, event)
            if app.Streaming_SpectralAnalysis_DropDown.Value == "Select Option"
                app.Streaming_ApplyButton.Enable = false;
            else
                app.Streaming_ApplyButton.Enable = true;
            end
        end

        % Button pushed function: Streaming_ApplyButton
        function Streaming_ApplyButtonPushed(app, event)
            value = app.Streaming_SpectralAnalysis_DropDown.Value;
            app.ReadyLamp_Label.Text = 'Busy';
            app.ReadyLamp.Color = [0.64,0.08,0.18];
            drawnow;
            app.SystemInformation_ApplyButton.Enable = false;
            app.Calibration_ApplyButton.Enable = false;
            app.LoadFileButton.Enable = false;

            % Analysis for streaming data
            switch app.Streaming_SpectralAnalysis_DropDown.Value

                case 'Spectrogram'
                    VisualizationWindow( app.obj, app.current_patient,  "Stream Spectrogram", 0 );
                    app.Display_TextArea.Value = [ app.Display_TextArea.Value; "# Running: plot Streaming Spectrogram."; '       ' ];
                case 'PSD Welch'
                    VisualizationWindow( app.obj, app.current_patient,  "Stream Welch", 0 );
                    app.Display_TextArea.Value = [ app.Display_TextArea.Value; "# Running: plot Streaming Welch."; '       ' ];
                case 'Morlet Wavelet'
                    VisualizationWindow( app.obj, app.current_patient,  "Stream Wavelet", 0 );
                    app.Display_TextArea.Value = [ app.Display_TextArea.Value; "# Running: plot Streaming Wavelet."; '       ' ];
                case 'Auto-correlation'
                    VisualizationWindow( app.obj, app.current_patient,  "Stream AutoCorr", 0 );
                    app.Display_TextArea.Value = [ app.Display_TextArea.Value; "# Running: plot Streaming AutoCorrelation."; '       ' ];
                case 'Cross-correlation'
                    VisualizationWindow( app.obj, app.current_patient,  "Stream CrossCorr", 0 );
                    app.Display_TextArea.Value = [ app.Display_TextArea.Value; "# Running: plot Streaming CrossCorrelation."; '       ' ];
                case 'Hemispherical Coherence'
                    VisualizationWindow( app.obj, app.current_patient,  "Stream Coherence", 0 );
                    app.Display_TextArea.Value = [ app.Display_TextArea.Value; "# Running: plot Streaming Coherence."; '       ' ];
                otherwise
            end

            app.SystemInformation_ApplyButton.Enable = true;
            app.Calibration_ApplyButton.Enable = true;
            app.LoadFileButton.Enable = true;
            app.ReadyLamp_Label.Text = 'Ready';
            app.ReadyLamp.Color = [0.47,0.67,0.19];
            drawnow;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
          
        end

        % Button pushed function: Chronic_ApplyButton
        function Chronic_ApplyButtonPushed(app, event)
            app.ReadyLamp_Label.Text = 'Busy';
            app.ReadyLamp.Color = [0.64,0.08,0.18];
            drawnow;
            app.SystemInformation_ApplyButton.Enable = false;
            app.Calibration_ApplyButton.Enable = false;
            app.LoadFileButton.Enable = false;
            
            % Correlation analysis for chronic data
            switch app.Chronic_DropDown.Value
                case 'Auto-correlation'
                    str = "chronic_auto";
                    str_text = "Chronic Auto-correlation";
                case 'Cross-correlation'
                    str = "chronic_cross";
                    str_text = "Chronic Cross-correlation";
                case "Circadian Profile"
                    str = "circadian";
                    str_text = "Circadian Profile";
                otherwise
            end

            VisualizationWindow( app.obj, app.current_patient, str, 0 );
            app.Display_TextArea.Value = [ app.Display_TextArea.Value; "# Running: plot " + str_text + "."; '       ' ];
            
            app.ReadyLamp_Label.Text = 'Ready';
            app.ReadyLamp.Color = [0.47,0.67,0.19];
            drawnow;
            app.SystemInformation_ApplyButton.Enable = true;
            app.Calibration_ApplyButton.Enable = true;
            app.LoadFileButton.Enable = true;
        end

        % Button pushed function: Chronic_Events_Button
        function Chronic_Events_ButtonButtonPushed(app, event)
            switch app.Chronic_Events_DropDown.Value
                case "FFT Profile"
                    str = "events_FFT";
                    str_text = "Event-FFT Profiles";
                case "Temporal Information"
                    str = "events_histogram";
                    str_text = "Event Temporal Analysis";
                case "Event-Triggered Average"
                    str = "ETA";
                    str_text = "Event-Triggered Average";
            end
            VisualizationWindow( app.obj, app.current_patient, str, 0 );
            app.Display_TextArea.Value = [ app.Display_TextArea.Value; "# Running: plot " + str_text + "."; '       ' ];

        end

        % Button pushed function: AnonymizeDataButton
        function AnonymizeDataButtonPushed(app, event)
            app.obj.createAnonymized();
        end

        % Value changed function: Chronic_DropDown
        function Chronic_DropDownValueChanged(app, event)
            if app.Chronic_DropDown.Value == "Select Option"
                app.Chronic_ApplyButton.Enable = false;
            else
                app.Chronic_ApplyButton.Enable = true;
            end            
        end

        % Value changed function: Chronic_Events_DropDown
        function Chronic_Events_DropDownValueChanged(app, event)
            if app.Chronic_Events_DropDown.Value == "Select Option"
                app.Chronic_Events_Button.Enable = false;
            else
                app.Chronic_Events_Button.Enable = true;
            end            
        end

        % Value changed function: PatientIDDropDown
        function PatientIDDropDownValueChanged(app, event)
            app.current_patient = app.PatientIDDropDown.Value;
            app.Streaming_FilterData_Button.Enable = false;
            app.StreamingSummary_Button.Enable = false;
            app.Streaming_SpectralAnalysis_DropDown.Value = "Select Option";
            app.Streaming_SpectralAnalysis_DropDown.Enable = false;
            app.Streaming_ApplyButton.Enable = false;
            app.LoadWearablesButton.Enable = false;
            app.enableSections();

            text = (["======================="; "CHANGED TO PATIENT: " + ...
                app.obj.patient_list(app.current_patient); "======================="]);
            app.Display_TextArea.Value = [ app.Display_TextArea.Value; text; '       ' ];
        end

        % Close request function: DBScopeUIFigure
        function DBScopeUIFigureCloseRequest(app, event)
            if ~isempty(app.obj.patient_list)
                answer = questdlg('Are you sure you want to exit DBScope?', ...
                    '', 'Yes & Export workspace','Yes & Delete workspace','No', 'No');
                switch answer
                    case 'Yes & Export workspace'
                        app.obj.saveWorkspace_single();
                    case 'Yes & Delete workspace'
                    case 'No'
                        return;
                end
                
                all_fig = findall(0, 'type', 'figure');            
                delete(all_fig);
            else
                app.delete;
            end
        end

        % Button pushed function: ClearText
        function ClearTextButtonPushed(app, event)
            app.Display_TextArea.Value = [ ' ' ];
        end

        % Button pushed function: LoadWearablesButton
        function LoadWearablesButtonPushed(app, event)
            % Get files to load
            [filenames, data_pathname] = uigetfile( '*.csv', 'Select .csv file', 'MultiSelect', 'on' );
            if ischar( filenames )
                filenames = {filenames};
            elseif isnumeric ( filenames )
                return;
            else
                filenames = filenames';
            end
            
            % Get dates of current patient visits
            for file = 1:numel( app.obj.ncnpercept_patient{ app.current_patient } )
                dates_ipg( file ) = app.obj.ncnpercept_patient{ app.current_patient }{ file }.parameters.session_date;
            end
            
            % Get dates of wearable data
            for file = 1:numel( filenames )
                metadata                = strsplit( filenames{file}, '_' );
                dates_wearables( file ) = datetime( metadata(5), "InputFormat", 'yyyyMMdd''T''HHmmss''.csv''');
            end
            
            % Load wearable recordings to respective visit file
            for dt = 1:numel( dates_ipg )
                % Check wearable recordings with the same day of visit
                temp_filenames = { filenames{ dates_wearables - dates_ipg(dt) < days(1) } };
                
                if numel( temp_filenames ) > 0 
                    app.obj.ncnpercept_patient{ app.current_patient }{ dt }.wearables_obj.loadWearables( data_pathname, temp_filenames );
                    app.Wearables_CrossAnalysisButton.Enable = true;
                end
            end
        end

        % Button pushed function: Wearables_CrossAnalysisButton
        function Wearables_CrossAnalysisButtonPushed(app, event)
            VisualizationWindow( app.obj, app.current_patient, "Wearable", 0 );
            app.Display_TextArea.Value = [ app.Display_TextArea.Value; "# Running: plot Wearable Cross-Analysis."; '       ' ];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DBScopeUIFigure = uifigure('Visible', 'off');
            app.DBScopeUIFigure.Name = 'DBScope';
            app.DBScopeUIFigure.Icon = 'Icon.png';
            app.DBScopeUIFigure.CloseRequestFcn = createCallbackFcn(app, @DBScopeUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.DBScopeUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.ButtonDownFcn = createCallbackFcn(app, @TabButtonDown, true);
            app.LoadFileButton = uibutton(app.Tab, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File(s)';
            app.Calibration_Panel = uipanel(app.Tab);
            app.Calibration_Panel.Title = 'Calibration Information';
            app.Calibration_InspectforArtifacts_Button = uibutton(app.Calibration_Panel, 'push');
            app.Calibration_InspectforArtifacts_Button.ButtonPushedFcn = createCallbackFcn(app, @Calibration_InspectforArtifacts_ButtonPushed, true);
            app.Calibration_InspectforArtifacts_Button.Enable = 'off';
            app.Calibration_InspectforArtifacts_Button.Text = 'Inspect for Artifacts';
            app.Calibration_DropDown = uidropdown(app.Calibration_Panel);
            app.Calibration_DropDown.Items = {'Select Option'};
            app.Calibration_DropDown.ValueChangedFcn = createCallbackFcn(app, @Calibration_DropDownValueChanged, true);
            app.Calibration_DropDown.Enable = 'off';
            app.Calibration_DropDown.Value = 'Select Option';
            app.Calibration_ApplyButton = uibutton(app.Calibration_Panel, 'push');
            app.Calibration_ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @Calibration_ApplyButtonPushed, true);
            app.Calibration_ApplyButton.Enable = 'off';
            app.Calibration_ApplyButton.Text = 'Apply';
            app.SystemInformationPanel = uipanel(app.Tab);
            app.SystemInformationPanel.Title = 'System Information';
            app.SystemInformation_DropDown = uidropdown(app.SystemInformationPanel);
            app.SystemInformation_DropDown.Items = {'Select Option', 'Session Information', 'Patient Information', 'Device Information', 'Battery Information', 'Lead Configuration', 'Stimulation Status', 'Groups Information'};
            app.SystemInformation_DropDown.ValueChangedFcn = createCallbackFcn(app, @SystemInformation_DropDownValueChanged, true);
            app.SystemInformation_DropDown.Enable = 'off';
            app.SystemInformation_DropDown.Value = 'Select Option';
            app.SystemInformation_ApplyButton = uibutton(app.SystemInformationPanel, 'push');
            app.SystemInformation_ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @SystemInformation_ApplyButtonPushed, true);
            app.SystemInformation_ApplyButton.Enable = 'off';
            app.SystemInformation_ApplyButton.Text = 'Apply';
            app.Closeall_Button = uibutton(app.Tab, 'push');
            app.Closeall_Button.ButtonPushedFcn = createCallbackFcn(app, @Closeall_ButtonPushed, true);
            app.Closeall_Button.Text = 'Close plots';
            app.Display_TextArea = uitextarea(app.Tab);
            app.Display_TextArea.ValueChangedFcn = createCallbackFcn(app, @Display_TextAreaValueChanged, true);
            app.Display_TextArea.Editable = 'off';
            app.Display_TextArea.Value = {'Waiting'};
            app.SaveWorkspace_Button = uibutton(app.Tab, 'push');
            app.SaveWorkspace_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveWorkspace_ButtonPushed, true);
            app.SaveWorkspace_Button.Text = 'Export Workspace';
            app.ReadyLamp = uilamp(app.Tab);
            app.ReadyLamp_Label = uilabel(app.Tab);
            app.ReadyLamp_Label.Text = 'Ready';
            app.Chronic_SensingPanel = uipanel(app.Tab);
            app.Chronic_SensingPanel.Title = 'Chronic Sensing';
            app.EventsPanel = uipanel(app.Chronic_SensingPanel);
            app.EventsPanel.Title = 'Events';
            app.Chronic_CoherencePanel = uipanel(app.Chronic_SensingPanel);
            app.Chronic_CoherencePanel.Title = 'Continuous Data';
            app.Chronic_DropDown = uidropdown(app.Chronic_CoherencePanel);
            app.Chronic_DropDown.Items = {'Select Option'};
            app.Chronic_DropDown.ValueChangedFcn = createCallbackFcn(app, @Chronic_DropDownValueChanged, true);
            app.Chronic_DropDown.Enable = 'off';
            app.Chronic_DropDown.Value = 'Select Option';
            app.Chronic_ApplyButton = uibutton(app.Chronic_CoherencePanel, 'push');
            app.Chronic_ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @Chronic_ApplyButtonPushed, true);
            app.Chronic_ApplyButton.Enable = 'off';
            app.Chronic_ApplyButton.Text = 'Apply';
            app.Chronic_Visualizedata_Button = uibutton(app.Chronic_SensingPanel, 'push');
            app.Chronic_Visualizedata_Button.ButtonPushedFcn = createCallbackFcn(app, @Chronic_Visualizedata_ButtonPushed, true);
            app.Chronic_Visualizedata_Button.Enable = 'off';
            app.Chronic_Visualizedata_Button.Text = 'Visualize';
            app.Chronic_Events_Button = uibutton(app.Chronic_SensingPanel, 'push');
            app.Chronic_Events_Button.ButtonPushedFcn = createCallbackFcn(app, @Chronic_Events_ButtonButtonPushed, true);
            app.Chronic_Events_Button.Enable = 'off';
            app.Chronic_Events_Button.Text = 'Apply';
            app.Chronic_Events_DropDown = uidropdown(app.Chronic_SensingPanel);
            app.Chronic_Events_DropDown.Items = {'Select Option', 'Event-Triggered Average', 'FFT Profile', 'Temporal Information'};
            app.Chronic_Events_DropDown.ValueChangedFcn = createCallbackFcn(app, @Chronic_Events_DropDownValueChanged, true);
            app.Chronic_Events_DropDown.Enable = 'off';
            app.Chronic_Events_DropDown.Value = 'Select Option';
            app.Streaming_OnlineStreamingSensing_Panel_2 = uipanel(app.Tab);
            app.Streaming_OnlineStreamingSensing_Panel_2.Title = 'Online Streaming Sensing';
            app.Streaming_FilterData_Button = uibutton(app.Streaming_OnlineStreamingSensing_Panel_2, 'push');
            app.Streaming_FilterData_Button.ButtonPushedFcn = createCallbackFcn(app, @Streaming_FilterData_ButtonPushed, true);
            app.Streaming_FilterData_Button.Enable = 'off';
            app.Streaming_FilterData_Button.Text = {' Filter Data'; ''};
            app.Streaming_SpectralAnalysisPanel = uipanel(app.Streaming_OnlineStreamingSensing_Panel_2);
            app.Streaming_SpectralAnalysisPanel.Title = 'Spectral & Coherence Analysis';
            app.Streaming_ApplyButton = uibutton(app.Streaming_SpectralAnalysisPanel, 'push');
            app.Streaming_ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @Streaming_ApplyButtonPushed, true);
            app.Streaming_ApplyButton.Enable = 'off';
            app.Streaming_ApplyButton.Text = 'Apply';
            app.Streaming_SpectralAnalysis_DropDown = uidropdown(app.Streaming_SpectralAnalysisPanel);
            app.Streaming_SpectralAnalysis_DropDown.Items = {'Select Option', 'Spectrogram', 'PSD Welch', 'Morlet Wavelet', 'Auto-correlation', 'Cross-correlation', 'Hemispherical Coherence'};
            app.Streaming_SpectralAnalysis_DropDown.ValueChangedFcn = createCallbackFcn(app, @Streaming_SpectralAnalysis_DropDownValueChanged, true);
            app.Streaming_SpectralAnalysis_DropDown.Enable = 'off';
            app.Streaming_SpectralAnalysis_DropDown.Value = 'Select Option';
            app.StreamingSummary_Button = uibutton(app.Streaming_OnlineStreamingSensing_Panel_2, 'push');
            app.StreamingSummary_Button.ButtonPushedFcn = createCallbackFcn(app, @StreamingSummary_ButtonPushed, true);
            app.StreamingSummary_Button.Enable = 'off';
            app.StreamingSummary_Button.Text = 'Data Summary';
            app.Streaming_CleanECG = uibutton(app.Streaming_OnlineStreamingSensing_Panel_2, 'push');
            app.Streaming_CleanECG.ButtonPushedFcn = createCallbackFcn(app, @Streaming_CleanECGButtonPushed, true);
            app.Streaming_CleanECG.Enable = 'off';
            app.Streaming_CleanECG.Text = 'Clean ECG';
            app.Streaming_WerablesPanel = uipanel(app.Streaming_OnlineStreamingSensing_Panel_2);
            app.Streaming_WerablesPanel.Title = 'Wearables';
            app.Wearables_CrossAnalysisButton = uibutton(app.Streaming_WerablesPanel, 'push');
            app.Wearables_CrossAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @Wearables_CrossAnalysisButtonPushed, true);
            app.Wearables_CrossAnalysisButton.Enable = 'off';
            app.Wearables_CrossAnalysisButton.Text = 'Cross Analysis';
            app.LoadWearablesButton = uibutton(app.Streaming_WerablesPanel, 'push');
            app.LoadWearablesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWearablesButtonPushed, true);
            app.LoadWearablesButton.Enable = 'off';
            app.LoadWearablesButton.Text = 'Load Wearable(s)';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = 'Neuroengineering and Computational Neuroscience, i3S 2023  |';
            app.AnonymizeDataButton = uibutton(app.Tab, 'push');
            app.AnonymizeDataButton.ButtonPushedFcn = createCallbackFcn(app, @AnonymizeDataButtonPushed, true);
            app.AnonymizeDataButton.Text = 'Anonymize Data';
            app.PatientLabel = uilabel(app.Tab);
            app.PatientLabel.Text = 'Patient ID:';
            app.PatientIDDropDown = uidropdown(app.Tab);
            app.PatientIDDropDown.Items = {};
            app.PatientIDDropDown.ValueChangedFcn = createCallbackFcn(app, @PatientIDDropDownValueChanged, true);
            app.PatientIDDropDown.Enable = 'off';
            app.PatientIDDropDown.Value = {};
            app.ClearText = uibutton(app.Tab, 'push');
            app.ClearText.ButtonPushedFcn = createCallbackFcn(app, @ClearTextButtonPushed, true);
            app.ClearText.Text = 'Clear Text';
            app.Hyperlink = uihyperlink(app.Tab);
            app.Hyperlink.URL = 'https://github.com/NCN-Lab/DBScope';
            app.Hyperlink.Text = 'https://github.com/NCN-Lab/DBScope';
            app.DBScopeLabel = uilabel(app.DBScopeUIFigure);
            app.DBScopeLabel.Text = 'DBScope';
            % Show the figure after all components are created
            app.DBScopeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DBScope

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DBScopeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DBScopeUIFigure)
        end
    end
end